classdef fit_laue_oscillations_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenLversion10UIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        OnlySimulateSwitch              matlab.ui.control.Switch
        OnlysimulatewithoutfittingSwitchLabel  matlab.ui.control.Label
        ModeLabel                       matlab.ui.control.Label
        Fileshouldconsistof2columnsxandIntensityLabel  matlab.ui.control.Label
        PleasefirstselectthefolderwithallMatlabfilesforthisprogramLabel  matlab.ui.control.Label
        ChoosedirectoryButton           matlab.ui.control.Button
        MatlabfolderinformationLabel    matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
        ButtonGroupUnitPlot_Import      matlab.ui.container.ButtonGroup
        cpsButton_Plot                  matlab.ui.control.RadioButton
        countsButton_Plot               matlab.ui.control.RadioButton
        ButtonGroupSpacePlot_Import     matlab.ui.container.ButtonGroup
        QButton_Plot                    matlab.ui.control.RadioButton
        ThetaButton_Plot                matlab.ui.control.RadioButton
        PlotLabel                       matlab.ui.control.Label
        LoaddataButton                  matlab.ui.control.Button
        x_endEditField_Import           matlab.ui.control.NumericEditField
        EndLabel                        matlab.ui.control.Label
        XaxisLabel                      matlab.ui.control.Label
        x_startEditField_Import         matlab.ui.control.NumericEditField
        DiffractiondataLabel            matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        DataLabel                       matlab.ui.control.Label
        ButtonGroupSpace_Import         matlab.ui.container.ButtonGroup
        QButton                         matlab.ui.control.RadioButton
        ThetaButton                     matlab.ui.control.RadioButton
        NextButton_Import               matlab.ui.control.Button
        WavelengthEditField_Import      matlab.ui.control.NumericEditField
        WavelengthLabel                 matlab.ui.control.Label
        Time_sEditField                 matlab.ui.control.NumericEditField
        TimepointsLabel                 matlab.ui.control.Label
        UnitLabel                       matlab.ui.control.Label
        ButtonGroupUnit_Import          matlab.ui.container.ButtonGroup
        cpsButton                       matlab.ui.control.RadioButton
        countsButton                    matlab.ui.control.RadioButton
        SampleidentifierEditField       matlab.ui.control.EditField
        SampleidentifierEditFieldLabel  matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        SampleinformationLabel          matlab.ui.control.Label
        PlotsettingsLabel               matlab.ui.control.Label
        Plotting_Import                 matlab.ui.control.UIAxes
        SimulateTab                     matlab.ui.container.Tab
        AdjustPanel                     matlab.ui.container.Panel
        ExportgraphButton               matlab.ui.control.Button
        alpha_2EditField                matlab.ui.control.NumericEditField
        alpha_2EditFieldLabel           matlab.ui.control.Label
        alpha_1EditField                matlab.ui.control.NumericEditField
        alpha_1EditFieldLabel           matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        sigmaLabel                      matlab.ui.control.Label
        AdjustLabel                     matlab.ui.control.Label
        WavelengthEditField_2Label_2    matlab.ui.control.Label
        SampleNameTextAreaLabel_5       matlab.ui.control.Label
        StrainedSwitch                  matlab.ui.control.Switch
        RoughnessSwitch                 matlab.ui.control.Switch
        SampleNameTextAreaLabel_2       matlab.ui.control.Label
        ExportsampleButton              matlab.ui.control.Button
        ImportsampleButton              matlab.ui.control.Button
        ResetallButton                  matlab.ui.control.Button
        ButtonGroupStrain_Simulate      matlab.ui.container.ButtonGroup
        CheckBox                        matlab.ui.control.CheckBox
        compressiveButton               matlab.ui.control.RadioButton
        tensileButton                   matlab.ui.control.RadioButton
        SavesampleButton                matlab.ui.control.Button
        ZEditFieldLabel                 matlab.ui.control.Label
        compatEditFieldLabel            matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        thicknessEditField              matlab.ui.control.NumericEditField
        tLabel                          matlab.ui.control.Label
        SpacingEditField                matlab.ui.control.NumericEditField
        compatEditField                 matlab.ui.control.EditField
        dwEditField_Simulate            matlab.ui.control.NumericEditField
        ZEditField                      matlab.ui.control.EditField
        SimulateLabel                   matlab.ui.control.Label
        PreviousButton_Simulate         matlab.ui.control.Button
        NextButton_Simulate             matlab.ui.control.Button
        PlotsettingsLabel_2             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        x_endEditField_Simulate         matlab.ui.control.NumericEditField
        EndLabel_2                      matlab.ui.control.Label
        x_startEditField_Simulate       matlab.ui.control.NumericEditField
        StartLabel_2                    matlab.ui.control.Label
        XaxisLabel_Simulate             matlab.ui.control.Label
        PlotLabel_3                     matlab.ui.control.Label
        ButtonGroupSpacePlot_Simulate   matlab.ui.container.ButtonGroup
        QButton_Simulate                matlab.ui.control.RadioButton
        ThetaButton_Simulate            matlab.ui.control.RadioButton
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        ButtonGroupStrain_Simulate_cap  matlab.ui.container.ButtonGroup
        CheckBox_2                      matlab.ui.control.CheckBox
        compressiveButton_cap           matlab.ui.control.RadioButton
        tensileButton_cap               matlab.ui.control.RadioButton
        NameEditField_Simulate          matlab.ui.control.EditField
        SampleNameTextAreaLabel_4       matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        SampleinformationLabel_2        matlab.ui.control.Label
        SampleNameTextArea              matlab.ui.control.TextArea
        SubstrateSwitch                 matlab.ui.control.Switch
        SampleNameTextAreaLabel_3       matlab.ui.control.Label
        WavelengthEditField_2Label_4    matlab.ui.control.Label
        densityEditField                matlab.ui.control.NumericEditField
        dsubEditField                   matlab.ui.control.NumericEditField
        HWHMdegreesEditField            matlab.ui.control.NumericEditField
        IcpsEditField                   matlab.ui.control.NumericEditField
        IcpsEditField_2Label            matlab.ui.control.Label
        FWHMdegreesLabel                matlab.ui.control.Label
        dLabel_2                        matlab.ui.control.Label
        InstrumentLabel                 matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        MonochromatoronsideofSwitch     matlab.ui.control.Switch
        MonochromatoronsideofLabel      matlab.ui.control.Label
        theta_m                         matlab.ui.control.NumericEditField
        WavelengthEditField_2Label_6    matlab.ui.control.Label
        WavelengthEditField_Simulate    matlab.ui.control.NumericEditField
        WavelengthEditField_2Label_5    matlab.ui.control.Label
        SourceLabel                     matlab.ui.control.Label
        PolarizationcorrectionLabel     matlab.ui.control.Label
        Plotting_Simulate               matlab.ui.control.UIAxes
        FitTab                          matlab.ui.container.Tab
        FittingparametersLabel          matlab.ui.control.Label
        RefreshProgressButton           matlab.ui.control.Button
        ProgressLabel                   matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
        SimulateButton                  matlab.ui.control.Button
        BackgroundSwitch                matlab.ui.control.Switch
        BackgroundSwitchLabel           matlab.ui.control.Label
        dsub_limit_low                  matlab.ui.control.NumericEditField
        dsub_startField                 matlab.ui.control.NumericEditField
        dsubCheckBox                    matlab.ui.control.CheckBox
        w_limit_low                     matlab.ui.control.NumericEditField
        w_startField                    matlab.ui.control.NumericEditField
        w_subCheckBox                   matlab.ui.control.CheckBox
        I_limit_low                     matlab.ui.control.NumericEditField
        I_startField                    matlab.ui.control.NumericEditField
        I_subCheckBox                   matlab.ui.control.CheckBox
        dsub_limit_high                 matlab.ui.control.NumericEditField
        w_limit_high                    matlab.ui.control.NumericEditField
        I_limit_high                    matlab.ui.control.NumericEditField
        bkgc_limit_low                  matlab.ui.control.NumericEditField
        bkgc_startField                 matlab.ui.control.NumericEditField
        cCheckBox                       matlab.ui.control.CheckBox
        bkgc_limit_high                 matlab.ui.control.NumericEditField
        sigma_limit_low                 matlab.ui.control.NumericEditField
        sigma_startField                matlab.ui.control.NumericEditField
        sigmaCheckBox                   matlab.ui.control.CheckBox
        sigma_limit_high                matlab.ui.control.NumericEditField
        ButtonGroupUnitPlot_Fit         matlab.ui.container.ButtonGroup
        cpsButton_Plot_Fit              matlab.ui.control.RadioButton
        countsButton_Plot_Fit           matlab.ui.control.RadioButton
        ButtonGroupSpacePlot_Fit        matlab.ui.container.ButtonGroup
        QButton_Plot_Fit                matlab.ui.control.RadioButton
        ThetaButton_Plot_Fit            matlab.ui.control.RadioButton
        UpperlimitLabel                 matlab.ui.control.Label
        resolution_limit_high           matlab.ui.control.NumericEditField
        amplitude_limit_high            matlab.ui.control.NumericEditField
        bkgb_limit_high                 matlab.ui.control.NumericEditField
        bkga_limit_high                 matlab.ui.control.NumericEditField
        alpha2_limit_high               matlab.ui.control.NumericEditField
        alpha1_limit_high               matlab.ui.control.NumericEditField
        N_limit_high                    matlab.ui.control.NumericEditField
        d_limit_high                    matlab.ui.control.NumericEditField
        DisplayDropDown                 matlab.ui.control.DropDown
        DisplayDropDownLabel            matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        resolution_limit_low            matlab.ui.control.NumericEditField
        resolution_startField           matlab.ui.control.NumericEditField
        resolutionCheckBox              matlab.ui.control.CheckBox
        amplitude_limit_low             matlab.ui.control.NumericEditField
        amplitude_startField            matlab.ui.control.NumericEditField
        scalefactCheckBox               matlab.ui.control.CheckBox
        bkgb_limit_low                  matlab.ui.control.NumericEditField
        bkgb_startField                 matlab.ui.control.NumericEditField
        bCheckBox                       matlab.ui.control.CheckBox
        bkga_limit_low                  matlab.ui.control.NumericEditField
        bkga_startField                 matlab.ui.control.NumericEditField
        aCheckBox                       matlab.ui.control.CheckBox
        alpha2_limit_low                matlab.ui.control.NumericEditField
        alpha2_startField               matlab.ui.control.NumericEditField
        alpha_2CheckBox                 matlab.ui.control.CheckBox
        alpha1_limit_low                matlab.ui.control.NumericEditField
        alpha1_startField               matlab.ui.control.NumericEditField
        alpha_1CheckBox                 matlab.ui.control.CheckBox
        N_limit_low                     matlab.ui.control.NumericEditField
        N_startField                    matlab.ui.control.NumericEditField
        NCheckBox                       matlab.ui.control.CheckBox
        d_limit_low                     matlab.ui.control.NumericEditField
        d_startField                    matlab.ui.control.NumericEditField
        LowerlimitLabel                 matlab.ui.control.Label
        StartLabel_Fit                  matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        dCheckBox                       matlab.ui.control.CheckBox
        StopfittingButton               matlab.ui.control.Button
        StartfittingButton              matlab.ui.control.Button
        FittingLabel                    matlab.ui.control.Label
        PreviousButton_Fit              matlab.ui.control.Button
        ClearallButton_Fit              matlab.ui.control.Button
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        PlotsettingsLabel_3             matlab.ui.control.Label
        PlotLabel_2                     matlab.ui.control.Label
        x_endEditField_Fit              matlab.ui.control.NumericEditField
        EndLabel_3                      matlab.ui.control.Label
        x_startEditField_Fit            matlab.ui.control.NumericEditField
        StartLabel_4                    matlab.ui.control.Label
        XaxisLabel_2                    matlab.ui.control.Label
        ExportfitButton                 matlab.ui.control.Button
        ExportparameterButton           matlab.ui.control.Button
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        Plotting_Fit                    matlab.ui.control.UIAxes
    end

    %% PROPERTIES
    properties (Access = private)
        % from import tab
        filename; % name of data file
        data; % imported data
        Q_data; % Q vector for data
        Q; % Q vector
        twotheta_data % 2 Theta vector for data
        twotheta; % 2 Theta vector
        uY1; % Y error
        Y1; % Y intensity, always in cps
        datadownload % for downloading data
        path_d; % path to data
        file_d; % data file
        
        % from simulate tab
        layers = {}; % contains layer information
        sampleStr = ''; % text containing layer information
        header; % header of samplename text box
        thinfilm; % thin film
        strained = [0,0];
        
        % from the calculations
        formfactors; % array that contains all form factors information
        formfactorsQ; % array that contains all form factors Q dependent information
        I; % Intensity based on simulations
        mu; % absorption coefficient in 1/Å
        
        % from fitting tab
        fitting = {}; % start/fitted parameter
        lower = {}; % lower limit
        higher = {}; % upper limit
        GKP_bestmem; % start values for fitting
        FVr_minbound; % min bounds for fitting
        FVr_maxbound; % max bounds for fitting
        parameters; % fitting parameters for download
        FVr_temp; % fitting objective parameters
        S_struct; % fitting settings
        p; % parameters from best fit
        S_y; % results from fit
        I_nf; % results from fit
        f_bkg; % background function for fitting
        I_fit; % I based on simulated fit
        last; % 'fit', 'sim', oder 'none'
    end
    
    methods (Access = private)
        
        %% FUNCTIONS
        
        % start again from selecting a folder
        function back_to_start(app)
            % clean up everything
            disable_Import(app);
            set(app.OnlySimulateSwitch, 'Enable', 'off');

            % prepare folder selection
            % enable fitting only
            set(app.ChoosedirectoryButton, 'Enable', 'on');  
            app.ChoosedirectoryButton.Text = 'Choose directory';
        end

        % disable the import tab
        function disable_Import(app)
            % disable change of unit
            set(app.ButtonGroupSpacePlot_Import, 'Enable', 'off');
            set(app.ButtonGroupUnitPlot_Import, 'Enable', 'off');
            set(app.PlotLabel, 'Enable', 'off');
            
            % disable import functions
            set(app.SampleidentifierEditField, 'Enable', 'off');
            set(app.LoaddataButton, 'Enable', 'off');
            set(app.ButtonGroupUnit_Import, 'Enable', 'off');
            set(app.Time_sEditField, 'Enable', 'off');
            set(app.ButtonGroupSpace_Import, 'Enable', 'off');
            set(app.WavelengthEditField_Import, 'Enable', 'off');
            set(app.x_startEditField_Import, 'Enable', 'off');
            set(app.x_endEditField_Import, 'Enable', 'off');
            set(app.DeleteButton, 'Enable', 'off');

            % disable download
            set(app.ExportdataButton, 'Enable', 'off');
           
            % disable going to the next tab
            set(app.NextButton_Import, 'Enable', 'off');
        end

        % prepare the import tab to use it
        function prepare_Imported(app)
            % enable change of unit
            set(app.ButtonGroupSpacePlot_Import, 'Enable', 'on');
            set(app.ButtonGroupUnitPlot_Import, 'Enable', 'on');
            set(app.PlotLabel, 'Enable', 'on');
            
            % disable import functions
            set(app.SampleidentifierEditField, 'Enable', 'off');
            set(app.LoaddataButton, 'Enable', 'off');
            set(app.ButtonGroupUnit_Import, 'Enable', 'off');
            set(app.Time_sEditField, 'Enable', 'off');
            set(app.ButtonGroupSpace_Import, 'Enable', 'off');
            set(app.WavelengthEditField_Import, 'Enable', 'off');
            set(app.x_startEditField_Import, 'Enable', 'on');
            set(app.x_endEditField_Import, 'Enable', 'on');

            % enable download
            set(app.ExportdataButton, 'Enable', 'on');
            
            % disable only simulate
            set(app.OnlySimulateSwitch, 'Enable', 'off');
            
            % enable going to the next tab
            set(app.NextButton_Import, 'Enable', 'on');
        end

        % clear the import tab to original state
        function clear_Import(app)
            % clear up all text and fields
            app.SampleidentifierEditField.Value = 'Sample';
            app.filename = [0,0];
            app.LoaddataButton.Text = 'Load data';
            app.countsButton.Value = true;
            app.cpsButton_Plot.Value = false;
            app.ThetaButton.Value = true;
            app.QButton.Value = false;
            app.Time_sEditField.Value = 1;
            app.WavelengthEditField_Import.Value = 1.5418;
            app.x_startEditField_Import.Value = 60;
            app.x_endEditField_Import.Value = 70;
            app.OnlySimulateSwitch.Value = app.OnlySimulateSwitch.Items{1};
            
            % clear figure import
            errorbar([], [], [], 'parent', app.Plotting_Import, 'Color', 'k')
            app.Plotting_Import.Title.String = '';
            app.Plotting_Import.YScale = 'linear';
            app.Plotting_Import.XLimMode = 'manual';
            app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
            app.Plotting_Import.XLabel.String = '2 Theta [degrees]';
            app.Plotting_Import.YLabel.String = 'Intensity [counts]';

            % disable import, enable delete
            app.ImportButton.Text = 'Import';
            set(app.ImportButton, 'Enable', 'off');
            set(app.DeleteButton, 'Enable', 'on');

            % enable fitting only
            set(app.OnlySimulateSwitch, 'Enable', 'on');         
            
            % disable download
            set(app.ExportdataButton, 'Enable', 'off');
            
            % enable import functions
            set(app.SampleidentifierEditField, 'Enable', 'on');
            set(app.LoaddataButton, 'Enable', 'on');
            set(app.ButtonGroupUnit_Import, 'Enable', 'on');
            set(app.Time_sEditField, 'Enable', 'on');
            set(app.ButtonGroupSpace_Import, 'Enable', 'on');
            set(app.WavelengthEditField_Import, 'Enable', 'on');
            set(app.x_startEditField_Import, 'Enable', 'off');
            set(app.x_endEditField_Import, 'Enable', 'off');
            
            % disable plot functions
            set(app.ButtonGroupUnitPlot_Import, 'Enable', 'off');
            set(app.ButtonGroupSpacePlot_Import, 'Enable', 'off');
            set(app.PlotLabel, 'Enable', 'off');
            
            % disable going to the next tab
            set(app.NextButton_Import, 'Enable', 'off');

            % buttons
            app.LoaddataButton.Text = 'Load data';

            % export text
            app.ExportdataButton.Text = 'Export data';
        end

        % prepare the simulate tab to use it
        function prepare_Simulate(app)
            % sample name
            app.NameEditField_Simulate.Value = app.SampleidentifierEditField.Value;
            set(app.SampleNameTextArea, 'Enable', 'on');

            % plot
            app.Plotting_Simulate.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
            if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                app.Plotting_Simulate.XLabel.String = '2 Theta [degrees]';
                app.QButton_Simulate.Value = false;
                app.ThetaButton_Simulate.Value = true;
            elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, 'Q')
                app.Plotting_Simulate.XLabel.String = 'Q [1/nm]';
                app.QButton_Simulate.Value = true;
                app.ThetaButton_Simulate.Value = false;
            end
            app.x_startEditField_Simulate.Value = app.x_startEditField_Import.Value;
            app.x_endEditField_Simulate.Value = app.x_endEditField_Import.Value;
            set(app.x_startEditField_Simulate, 'Enable', 'on');
            set(app.x_endEditField_Simulate, 'Enable', 'on');
            app.WavelengthEditField_Simulate.Value = app.WavelengthEditField_Import.Value;
            set(app.ButtonGroupSpacePlot_Simulate, 'Enable', 'off');

            % simulate thin film fields
            set(app.densityEditField, 'Enable', 'on');
            set(app.NameEditField_Simulate, 'Enable', 'on');
            set(app.ZEditField, 'Enable', 'on');
            set(app.compatEditField, 'Enable', 'on');
            set(app.SpacingEditField, 'Enable', 'on');
            set(app.thicknessEditField, 'Enable', 'on');
            set(app.StrainedSwitch, 'Enable', 'on');
            set(app.SavesampleButton, 'Enable', 'on');
            set(app.ResetallButton, 'Enable', 'on');
            set(app.ImportsampleButton, 'Enable', 'on');
            set(app.IcpsEditField, 'Enable', 'off');
            set(app.HWHMdegreesEditField, 'Enable', 'off');
            set(app.dsubEditField, 'Enable', 'off');
            set(app.SubstrateSwitch, 'Enable', 'on');
            set(app.RoughnessSwitch, 'Enable', 'on');
            set(app.dwEditField_Simulate, 'Enable', 'on');
            if strcmp(app.OnlySimulateSwitch.Value, 'No')
                set(app.WavelengthEditField_Simulate, 'Enable', 'off');
            elseif strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                set(app.WavelengthEditField_Simulate, 'Enable', 'on');
            end

            % buttons
            if ~strcmp(app.layers, '')
                set(app.DoneButton, 'Enable', 'off');
            else
                set(app.DoneButton, 'Enable', 'on');
            end
            set(app.DoneButton, 'Enable', 'off');

             % import/export buttons
            app.ImportsampleButton.Text = 'Import sample';
            app.ExportsampleButton.Text = 'Export sample';

            % instrumentation
            set(app.theta_m, 'Enable', 'on');
            set(app.MonochromatoronsideofSwitch, 'Enable', 'on');
        end

        % clear the simulate tab to original state
        function clear_Simulate(app)
                % sample name
                app.NameEditField_Simulate.Value = 'Iron';
                set(app.SampleNameTextArea, 'Enable', 'off');

                % plot
                errorbar([], [], [], 'parent', app.Plotting_Simulate, 'Color', 'k')
                app.Plotting_Simulate.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                    app.Plotting_Simulate.XLabel.String = '2 Theta [degrees]';
                    app.QButton_Simulate.Value = false;
                    app.ThetaButton_Simulate.Value = true;
                elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, 'Q')
                    app.Plotting_Simulate.XLabel.String = 'Q [1/nm]';
                    app.QButton_Simulate.Value = true;
                    app.ThetaButton_Simulate.Value = false;
                end
                app.x_startEditField_Simulate.Value = app.x_startEditField_Import.Value;
                app.x_endEditField_Simulate.Value = app.x_endEditField_Import.Value;
                app.WavelengthEditField_Simulate.Value = app.WavelengthEditField_Import.Value;
                set(app.ButtonGroupSpacePlot_Simulate, 'Enable', 'off');

                % simulate thin film fields
                set(app.densityEditField, 'Enable', 'off');
                set(app.NameEditField_Simulate, 'Enable', 'off');
                set(app.ZEditField, 'Enable', 'off');
                set(app.compatEditField, 'Enable', 'off');
                set(app.SpacingEditField, 'Enable', 'off');
                set(app.thicknessEditField, 'Enable', 'off');
                set(app.StrainedSwitch, 'Enable', 'off');
                set(app.SavesampleButton, 'Enable', 'off');
                set(app.ResetallButton, 'Enable', 'off');
                set(app.ImportsampleButton, 'Enable', 'off');
                set(app.ExportsampleButton, 'Enable', 'off');
                set(app.DoneButton, 'Enable', 'off');
                set(app.IcpsEditField, 'Enable', 'off');
                set(app.HWHMdegreesEditField, 'Enable', 'off');
                set(app.dsubEditField, 'Enable', 'off');
                set(app.SubstrateSwitch, 'Enable', 'off');
                set(app.RoughnessSwitch, 'Enable', 'off');
                set(app.WavelengthEditField_Simulate, 'Enable', 'off');
                set(app.dwEditField_Simulate, 'Enable', 'off');

                % buttons
                app.DoneButton.Text = 'Done';

                % import/export buttons
                app.ImportsampleButton.Text = 'Import sample';
                app.ExportsampleButton.Text = 'Export sample';

                % instrumentation
                set(app.theta_m, 'Enable', 'off');
                set(app.MonochromatoronsideofSwitch, 'Enable', 'off');
        end

        % check whether values work for valid simulation
        function checked = check_Simulate(app)
            % check composition
            temp_comp = str2double(split(string(app.compatEditField.Value), ';'));
            sum = 0;
            for i=1:length(temp_comp)
                sum = sum + temp_comp(i);
            end

            % test if someone entered something wrong
            if length(str2double(split(string(app.ZEditField.Value), ';'))) ~= length(str2double(split(string(app.compatEditField.Value), ';')))
                app.SampleNameTextArea.Value = "Z needs to have as many arguments as the composition. Please try again.";
                checked = false;
            elseif any(ismember(str2double(split(string(app.ZEditField.Value), ';')),[0]))
                app.SampleNameTextArea.Value = "No element with Z = 0. Please try again.";
                checked = false;
            elseif app.dwEditField_Simulate.Value >= 0
                app.SampleNameTextArea.Value = "Please add a negative Debye-Waller pre-factor and try again.";
                checked = false;
            elseif sum ~= 100
                app.SampleNameTextArea.Value = "At.% must add up to 100. Please try again.";
                checked = false;
            elseif app.densityEditField.Value == 0
                app.SampleNameTextArea.Value = "The density cannot be 0 atoms/Å^3. Please try again.";
                checked = false;
            elseif app.SpacingEditField.Value == 0
                app.SampleNameTextArea.Value = "Spacing must be larger than 0 Å. Please try again.";
                checked = false;
            elseif app.thicknessEditField.Value == 0
                app.SampleNameTextArea.Value = "Thickness must be larger than 0 Å. Please try again.";
                checked = false;
            elseif  app.dsubEditField.Value == 0
                app.SampleNameTextArea.Value = "Substrate spacing must be larger than 0 Å. Please try again.";
                checked = false;
            else
                checked = true;

                if strcmp(app.StrainedSwitch.Value, 'Yes')
                    if app.CheckBox.Value == 0
                        if app.CheckBox_2 == 0
                            app.SampleNameTextArea.Value = "If you consider strain, you need to check at least either the CheckBox for the substrate or the cap.";
                            checked = false;
                        end
                    end
                end
            end
        end

        % reset all fields for simulation of a thin film
        function reset_Simulate(app)
            % remove data
            app.layers = {};
            app.sampleStr = '';
            app.SampleNameTextArea.Value = "The thin film layering has been deleted. Please start again.";
            
            % reset the simulation tab
            app.densityEditField.Value = 0;
            app.NameEditField_Simulate.Value = "Iron";
            app.ZEditField.Value = "Z1; Z2";
            app.compatEditField.Value = "x1; x2";
            app.SpacingEditField.Value = 1;
            app.thicknessEditField.Value = 1;
            app.SubstrateSwitch.Value = 'No';
            app.IcpsEditField.Value = 1;
            app.HWHMdegreesEditField.Value = 0.001;
            app.dsubEditField.Value = 1;
            app.StrainedSwitch.Value = 'No';
            set(app.ButtonGroupStrain_Simulate, 'Enable', 'off');
            set(app.CheckBox, 'Enable', 'off');
            app.CheckBox.Value = 0;
            app.tensileButton.Value = true;
            app.compressiveButton.Value = false;
            set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'off');
            set(app.CheckBox_2, 'Enable', 'off');
            app.CheckBox_2.Value = 0;
            app.tensileButton_cap.Value = true;
            app.compressiveButton_cap.Value = false;
            app.RoughnessSwitch.Value = 'No';
            app.WavelengthEditField_Simulate.Value = 1.5418;
            app.dwEditField_Simulate.Value = 0;
            
            % unable being done
            set(app.DoneButton, 'Enable', 'off');
            app.DoneButton.Text = 'Done';

            % plot
            errorbar([], [], [], 'parent', app.Plotting_Simulate, 'Color', 'k')
            app.Plotting_Simulate.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
            if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                app.Plotting_Simulate.XLabel.String = '2 Theta [degrees]';
                app.QButton_Simulate.Value = false;
                app.ThetaButton_Simulate.Value = true;
            elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, 'Q')
                app.Plotting_Simulate.XLabel.String = 'Q [1/nm]';
                app.QButton_Simulate.Value = true;
                app.ThetaButton_Simulate.Value = false;
            end

            % disable downloading but enable uploading
            set(app.ExportsampleButton, 'Enable', 'off');
            set(app.ImportsampleButton, 'Enable', 'on');

            % instrumentation
            set(app.theta_m, 'Enable', 'on');
            set(app.MonochromatoronsideofSwitch, 'Enable', 'on');

            % switch on upper half panel and off lower one
            prepare_Simulate(app);

             % import/export buttons
            app.ImportsampleButton.Text = 'Import sample';
            app.ExportsampleButton.Text = 'Export sample';
        end

        % prepare simulated simulate tab
        function prepare_Simulated(app)
            % change the plot settings in the simulate tab
            app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];
            set(app.x_startEditField_Simulate, 'Enable', 'on');
            set(app.x_endEditField_Simulate, 'Enable', 'on');
            set(app.ButtonGroupSpacePlot_Simulate, 'Enable', 'on');
            
            % dis- and enable buttons in simulate tab

            % next
            if strcmp(app.OnlySimulateSwitch.Value, 'No')
                set(app.NextButton_Simulate, 'Enable', 'on');
            elseif strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                set(app.NextButton_Simulate, 'Enable', 'off');
            end

            % uoload and download
            set(app.ImportsampleButton, 'Enable', 'off');
            set(app.ExportsampleButton, 'Enable', 'on');
            app.ExportsampleButton.Text = 'Export thin film';
            
            % layer buttons
            set(app.ResetallButton, 'Enable', 'off');
            set(app.SavesampleButton, 'Enable', 'off');
            
            % done button
            app.DoneButton.Text = 'Edit';
            
            % simulate thin film fields
            set(app.densityEditField, 'Enable', 'off');
            set(app.NameEditField_Simulate, 'Enable', 'off');
            set(app.ZEditField, 'Enable', 'off');
            set(app.compatEditField, 'Enable', 'off');
            set(app.SpacingEditField, 'Enable', 'off');
            set(app.thicknessEditField, 'Enable', 'off');
            set(app.StrainedSwitch, 'Enable', 'off');
            set(app.ButtonGroupStrain_Simulate, 'Enable', 'off');
            set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'off');
            set(app.SavesampleButton, 'Enable', 'off');
            set(app.ResetallButton, 'Enable', 'off');
            set(app.ImportsampleButton, 'Enable', 'off');
            set(app.DoneButton, 'Enable', 'on');
            set(app.IcpsEditField, 'Enable', 'off');
            set(app.HWHMdegreesEditField, 'Enable', 'off');
            set(app.dsubEditField, 'Enable', 'off');
            set(app.SubstrateSwitch, 'Enable', 'off');
            set(app.RoughnessSwitch, 'Enable', 'off');
            set(app.WavelengthEditField_Simulate, 'Enable', 'off');
            set(app.dwEditField_Simulate, 'Enable', 'off');

            % instrumentation
            set(app.theta_m, 'Enable', 'off');
            set(app.MonochromatoronsideofSwitch, 'Enable', 'off');
        end

        % prepare simulated simulate tab
        function edit_Simulate(app)
            % change the plot settings in the simulate tab
            set(app.x_startEditField_Simulate, 'Enable', 'on');
            set(app.x_endEditField_Simulate, 'Enable', 'on');
            set(app.ButtonGroupSpacePlot_Simulate, 'Enable', 'off');
            
            % dis- and enable buttons in simulate tab

            % next
            set(app.NextButton_Simulate, 'Enable', 'off');

            % uoload and download
            set(app.ImportsampleButton, 'Enable', 'on');
            set(app.ExportsampleButton, 'Enable', 'off');
            app.ExportsampleButton.Text = 'Download thin film';
            
            % layer buttons
            set(app.ResetallButton, 'Enable', 'on');
            set(app.SavesampleButton, 'Enable', 'on');
            
            % done button
            app.DoneButton.Text = 'Re-do';
            
            % simulate thin film fields
            set(app.densityEditField, 'Enable', 'on');
            set(app.NameEditField_Simulate, 'Enable', 'on');
            set(app.ZEditField, 'Enable', 'on');
            set(app.compatEditField, 'Enable', 'on');
            set(app.SpacingEditField, 'Enable', 'on');
            set(app.thicknessEditField, 'Enable', 'on');
            set(app.StrainedSwitch, 'Enable', 'on');
            if strcmp(app.StrainedSwitch.Value, 'Yes')
                set(app.ButtonGroupStrain_Simulate, 'Enable', 'on');
                set(app.CheckBox, 'Enable', 'on');
                set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'on');
                set(app.CheckBox_2, 'Enable', 'on');
            else
                set(app.ButtonGroupStrain_Simulate, 'Enable', 'off');
                set(app.CheckBox, 'Enable', 'off');
                set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'off');
                set(app.CheckBox_2, 'Enable', 'off');
            end
            set(app.SavesampleButton, 'Enable', 'on');
            set(app.ResetallButton, 'Enable', 'on');
            set(app.ImportsampleButton, 'Enable', 'on');
            set(app.DoneButton, 'Enable', 'on');
            if strcmp(app.SubstrateSwitch.Value, 'Yes')
                set(app.IcpsEditField, 'Enable', 'on');
                set(app.HWHMdegreesEditField, 'Enable', 'on');
                set(app.dsubEditField, 'Enable', 'on');
            else
                set(app.IcpsEditField, 'Enable', 'off');
                set(app.HWHMdegreesEditField, 'Enable', 'off');
                set(app.dsubEditField, 'Enable', 'off');
            end
            set(app.SubstrateSwitch, 'Enable', 'on');
            set(app.RoughnessSwitch, 'Enable', 'on');
            set(app.dwEditField_Simulate, 'Enable', 'on');

            if strcmp(app.OnlySimulateSwitch.Value, 'No')
                set(app.WavelengthEditField_Simulate, 'Enable', 'off');
            elseif strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                set(app.WavelengthEditField_Simulate, 'Enable', 'on');
            end

            % disable fitting
            set(app.StartfittingButton, 'Enable', 'off');

            % instrumentation
            set(app.theta_m, 'Enable', 'on');
            set(app.MonochromatoronsideofSwitch, 'Enable', 'on');
        end

        % prepare the fit tab to use it
        function prepare_Fit(app)
            % delete simulations of the fit
            app.SimulateButton.Text = 'Simulate';
            app.StartfittingButton.Text = 'Start fitting';

            % enable editing
            enable_fit(app);

            % enable simulating
            set(app.SimulateButton, 'Enable', 'on');
            
            % create cell for fitting, lower, and upper limits
            app.fitting = {};
            app.lower = {};
            app.higher = {};

            % 1: d
            app.fitting{1} = app.layers{4};
            app.lower{1} = app.fitting{1}*0.98;
            app.higher{1} = app.fitting{1}*1.08;
            
            % 2: N
            app.fitting{2} = round(app.layers{5}/app.layers{4});
            app.lower{2} = app.fitting{2}*0.85;
            app.higher{2} = app.fitting{2};

            % 3: alpha 1, 4: alpha 2
            app.fitting{3} = 0;                
            app.lower{3} = 0;
            app.higher{3} = 0;

            app.fitting{4} = 0;
            app.lower{4} = 0;
            app.higher{4} = 0;
            if app.strained(1) ~= 0
                app.fitting{3} = 0.001;
                app.lower{3} = -0.05;
                app.higher{3} = 0.1;
            end
            if app.strained(2) ~= 0
                app.fitting{4} = 0.001;                
                app.lower{4} = -0.05;
                app.higher{4} = 0.1;
            end

            % 5: sigma
            if strcmp(app.RoughnessSwitch.Value, 'Yes')
                app.fitting{5} = app.layers{5}/10;
                app.lower{5} = 0;
                app.higher{5} = app.layers{5}/5;
            else
                app.fitting{5} = 0;
                app.lower{5} = 0;
                app.higher{5} = 0;
            end

            % 6: bkg a, 7: bkg b, 8: bkg c
            if strcmp(app.BackgroundSwitch.Value, 'linear')
                app.fitting{6} = 1;            
                app.lower{6} = -1;
                app.higher{6} = 1;
    
                app.fitting{7} = 1;
                app.lower{7} = -1;
                app.higher{7} = 1;
    
                app.fitting{8} = 0;
                app.lower{8} = 0;
                app.higher{8} = 0;
            elseif strcmp(app.BackgroundSwitch.Value, 'polynomial')
                app.fitting{6} = 1;            
                app.lower{6} = -1;
                app.higher{6} = 1;
    
                app.fitting{7} = 1;
                app.lower{7} = -1;
                app.higher{7} = 1;
    
                app.fitting{8} = 0;
                app.lower{8} = 0;
                app.higher{8} = 0;
            end

            % 9: scale factor
            app.fitting{9} = 0.0014;            
            app.lower{9} = app.fitting{9}/20;
            app.higher{9} = app.fitting{9}*5;

            % 10: resolution
            app.fitting{10} = 0.005;            
            app.lower{10} = 2e-3;
            app.higher{10} = 0.006;

            % 11: I, 12: w, 13: dsub
            if strcmp(app.layers{6}, 'Sub')
                app.fitting{11} = app.layers{7};            
                app.lower{11} = app.layers{7}/10; 
                app.higher{11} = app.layers{7}*10;
    
                app.fitting{12} = app.layers{8};           
                app.lower{12} = app.layers{8}/10; 
                app.higher{12} = app.layers{8}*10; 

                app.fitting{13} = app.layers{9};            
                app.lower{13} = app.layers{9}*0.99;
                app.higher{13} = app.layers{9}*1.01;
            else
                app.fitting{11} = 0;            
                app.lower{11} = 0; 
                app.higher{11} = 0; 
    
                app.fitting{12} = 0;          
                app.lower{12} = 0; 
                app.higher{12} = 0; 

                app.fitting{13} = 0;            
                app.lower{13} = 0;
                app.higher{13} = 0;
            end

            % display the layers
            display_fit(app);
            
            % reset fitting
            set(app.StartfittingButton, 'Enable', 'on');
            set(app.ExportparameterButton, 'Enable', 'off');
            set(app.ExportfitButton, 'Enable', 'off');
            set(app.DisplayDropDown, 'Enable', 'on');
            set(app.StopfittingButton, 'Enable', 'off');
        end

        % clear the fit tab to its original state
        function clear_Fit(app)
            % delete simulations of the fit
            app.SimulateButton.Text = 'Simulate';

            % disable editing
            disable_fit(app);

            % clear fitting progress
            app.Gauge.Value = 0;

            % disable simulating
            set(app.SimulateButton, 'Enable', 'off');
            
            % create cell for fitting, lower, and upper limits
            app.fitting = {};
            app.lower = {};
            app.higher = {};

            for i = 1:13
                app.fitting{i} = 0;
                app.lower{i} = 0;
                app.higher{i} = 0;
            end

            % set switch back to linear
            app.BackgroundSwitch.Value = app.BackgroundSwitch.Items{1};

            % display the layers
            display_fit(app);
            
            % reset fitting
            set(app.StartfittingButton, 'Enable', 'off');
            set(app.ExportparameterButton, 'Enable', 'off');
            set(app.ExportfitButton, 'Enable', 'off');
            set(app.DisplayDropDown, 'Enable', 'off');
            set(app.StopfittingButton, 'Enable', 'off');

            % disable plotting
            set(app.ButtonGroupSpacePlot_Fit, 'Enable', 'off');
            set(app.ButtonGroupUnitPlot_Fit, 'Enable', 'off');
            set(app.x_startEditField_Fit, 'Enable', 'off');
            set(app.x_endEditField_Fit, 'Enable', 'off');

            % reset the plot
            app.ThetaButton_Plot_Fit.Value = 1;
            app.QButton_Plot_Fit.Value = 0;
            app.x_startEditField_Fit.Value = 60;
            app.x_endEditField_Fit.Value = 70;
            errorbar([], [], [], 'parent', app.Plotting_Fit);
            if strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, '2 Theta')
                app.Plotting_Fit.XLabel.String = '2 Theta [degrees]';
            elseif strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, 'Q')
                app.Plotting_Fit.XLabel.String = 'Q [1/Å]';
            end
            app.Plotting_Fit.YLabel.String = 'Intensity';

            set(app.ButtonGroupSpacePlot_Fit, 'Enable', 'off');
            app.Plotting_Fit.XLimMode = 'manual';
            app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];

            % reset gof
            global gofs;
            clear global gofs;

            % reset export
            app.ExportfitButton.Text = 'Export fit';
            app.ExportparameterButton.Text = 'Export parameter';

        end
        
        % simulate scattering pattern of a thin film
        function simulate(app)
            
            % if only fitting
            if strcmp(app.OnlySimulateSwitch.Value, 'No')
                % set arbitrary strain & roughness
                alpha_1 = 0.029;
                alpha_2 = 0.004;
                sigma = 4;
            elseif strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                % set strain & roughness
                alpha_1 = app.alpha_1EditField.Value;
                alpha_2 = app.alpha_2EditField.Value;
                sigma = app.sigmaEditField.Value;
            end

            if strcmp(app.OnlySimulateSwitch.Value, 'No')
                % create Q and twotheta
                app.Q = app.Q_data;
                app.twotheta = app.twotheta_data;
            elseif strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                % calculate theta and Q
                if strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, '2 Theta')
                    app.twotheta = transpose([app.x_startEditField_Simulate.Value:0.01:app.x_endEditField_Simulate.Value]);
                    app.Q = 4*pi/app.WavelengthEditField_Simulate.Value*sind(app.twotheta/2);
                elseif strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, 'Q')
                    app.Q = transpose([app.x_startEditField_Simulate.Value:0.01:app.x_endEditField_Simulate.Value]);
                    app.twotheta = asind(app.Q*app.WavelengthEditField_Simulate.Value/(4*pi));
                end
            end

            % calculate DWF
            DebyeWaller = 1*exp(app.dwEditField_Simulate.Value*(app.Q/4/pi).^2);

            % form factor
            calculate_ff(app);

            % intensity
            app.I = zeros(size(app.Q));

            % nmax
            Nmax = app.layers{5}/app.layers{4};

            % rounded number of layers
            Nlayers = round(Nmax);
        
            % calculate DWF
            DF = DebyeWaller.*app.formfactorsQ;

            % calculate the strucure factor

            % if layer roughness should be included in the fitting
            if strcmp(app.layers{11}, 'Rough') && sigma > 0
                
               % range of 3 sigma plus/minus for roughness to be included
                nvector = max(1,round(Nlayers-3*sigma)):1:max(1,round(Nlayers+3*sigma));
        
                % out-of-plane positions of the atoms
                x = cell(size(nvector));
                % positions
                pos = cell(size(nvector));
                % including roughness
                B = zeros(size(nvector));
                A = 1/(sqrt(2*pi)*sigma);
        
                % strain
                strain   = cell(size(nvector));
        
                for N = nvector
                
                    % out-of-plane positions of the atoms
                    x{N} = ones(1,N)*app.layers{4};
                    % position
                    p_pos      = cumsum(x{N});
                    % start position
                    pos{N} = [0 p_pos];
        
                    % including roughness
                    B(N) = A*exp(-(N-Nlayers)^2/(2*sigma^2));

                    % same array upside down
                    pos_ud{N} = flip(pos{N},2);
            
                    if app.strained(1) ~=0
                        strain_bottom{N} = exp(-app.alpha_1EditField.Value*pos{N});
                        if app.strained(1) == 1
                            pos{N} = pos{N} - strain_bottom{N};
                        elseif app.strained(1) == -1
                             pos{N} = pos{N} + strain_bottom{N};
                        end
                    end
                    % calculate cap strain
                    if app.strained(2) ~=0
                        strain_top{N} = exp(-app.alpha_2EditField.Value*pos_ud{N});
                        if app.strained(2) == 1
                            pos{N} = pos{N} + strain_top{N};
                        elseif app.strained(2) == -1
                             pos{N} = pos{N} - strain_top{N};
                        end
                    end
                end
                
                % initialization structure factor
                F = zeros(size(app.Q));
            
                % calculate structure factor
                for N = nvector
                    for i =1:length(app.Q)
                        F(i) = sum(exp(1i*app.Q(i).*pos{N}));
                    end
                    app.I = app.I + abs(F).^2*B(N); % calculated Intensity
                end
                
                % calculate intensity
                app.I = (DF).^2.*app.I;

            % if layer roughness should not be included in the fitting    
            elseif strcmp(app.layers{11}, 'NoRough')
        
                % out-of-plane positions of the atoms
                x = ones(1,round(Nmax))*app.layers{4};
                pos = cumsum(x);
                % start position
                pos = [0 pos];
                
                % same array upside down
                pos_ud = flip(pos,2);
                
                % calculate substrate strain
                if app.strained(1) ~= 0
                    strain_bottom = exp(-app.alpha_1EditField.Value*pos);
                    if app.strained(1) == 1
                        pos = pos - strain_bottom;
                    elseif app.strained(1) == -1
                         pos = pos + strain_bottom;
                    end
                end
                % calculate cap strain
                if app.strained(2) ~= 0
                    strain_top = exp(-app.alpha_2EditField.Value*pos_ud);
                    if app.strained(2) == 1
                        pos = pos + strain_top;
                    elseif app.strained(2) == -1
                         pos = pos - strain_top;
                    end
                end
                
                % initialization structure factor
                F = zeros(size(app.Q));

                % calculate structure factor
                for i =1:length(app.Q)
                    F(i) = DF(i)*sum(exp(1i*app.Q(i).*pos));
                end
        
                app.I = abs(F).^2; % calculated Intensity
            end

            % out-of-plane positions of the atoms
            x = ones(1,round(Nmax))*app.layers{4};
            pos = cumsum(x);
            % start position
            pos = [0 pos];
            
            % same array upside down
            pos_ud = flip(pos,2);
            
            % calculate substrate strain
            if app.strained(1) ~= 0
                strain_bottom = exp(-app.alpha_1EditField.Value*pos);
                if app.strained(1) == 1
                    pos = pos - strain_bottom;
                elseif app.strained(1) == -1
                     pos = pos + strain_bottom;
                end
            end
            % calculate cap strain
            if app.strained(2) ~= 0
                strain_top = exp(-app.alpha_2EditField.Value*pos_ud);
                if app.strained(2) == 1
                    pos = pos + strain_top;
                elseif app.strained(2) == -1
                     pos = pos - strain_top;
                end
            end
            
            % calculate the scattering
            theta = app.twotheta./2;
            tau = pos(end)*1e-10; % film thickness in m
            
            % calculate the intensity
            % without background and with fixed resolution and scale factor

            % optics on incident side
            if strcmp(app.MonochromatoronsideofSwitch.Value, 'source')
                app.I    = 2e-2*GaussConv(app.Q,app.I,0.0014).* (1-exp(-2*app.mu*tau./sind(theta))).* (1+cosd(2*app.theta_m.Value)^2.*cosd(2*theta).^2)./(1+cosd(2*app.theta_m.Value).^2).* 1./(sind(2*theta));
            % optics on detector side
            elseif strcmp(app.MonochromatoronsideofSwitch.Value, 'detector')
                app.I    = 2e-2*GaussConv(app.Q,app.I,0.0014).* (1-exp(-2*app.mu*tau./sind(theta))).* 0.5*(1+cosd(2*theta).^2*cosd(2*app.theta_m.Value).^2).* 1./(sind(2*theta));
            end

            % including scattering from the substrate
            if strcmp(app.layers{6}, 'Sub')
                x_lor_Q   = (2*pi)/app.layers{9};
                a_lor     = [app.layers{7} app.layers{8} x_lor_Q];
                I_lor     = lorentz(a_lor,app.Q);
                app.I         = I_lor + app.I;
            end
        end
        
        % calculate the form factors
        function calculate_ff(app)

            % form factors
            app.formfactors{1} = Read_form_factor_coefficients(app.layers{2},app.WavelengthEditField_Simulate.Value);
                
            % Q dependent form factors
            compo = app.layers{3}./100;
            [f1, f_sqrd_real1, f_av_sqrd_real1] = Form_factors(app.Q,  app.formfactors{1}, compo); % for each layer this displays Q dependent ff
            app.formfactorsQ = f1;

            % absorption coefficient
            re = 2.81794*1e-15*1e10; % in Å
            app.mu = 2*re*app.densityEditField.Value*app.WavelengthEditField_Simulate.Value*imag(f1); % in 1/Å
            app.mu = app.mu * 1e10; % in 1/m
        end
        
        % display the fitting parameters including their bounds
        function display_fit(app)
            % d
            app.d_startField.Value = app.fitting{1};
            app.d_limit_low.Value = app.lower{1};
            app.d_limit_high.Value = app.higher{1};
            
            app.N_startField.Value = app.fitting{2};
            app.N_limit_low.Value = app.lower{2};
            app.N_limit_high.Value = app.higher{2};
            
            app.alpha1_startField.Value = app.fitting{3};
            app.alpha1_limit_low.Value = app.lower{3};
            app.alpha1_limit_high.Value = app.higher{3};
            
            app.alpha2_startField.Value = app.fitting{4};
            app.alpha2_limit_low.Value = app.lower{4};
            app.alpha2_limit_high.Value = app.higher{4};

            app.sigma_startField.Value = app.fitting{5};
            app.sigma_limit_low.Value = app.lower{5};
            app.sigma_limit_high.Value = app.higher{5};
            
            app.bkga_startField.Value = app.fitting{6};
            app.bkga_limit_low.Value = app.lower{6};
            app.bkga_limit_high.Value = app.higher{6};
            
            app.bkgb_startField.Value = app.fitting{7};
            app.bkgb_limit_low.Value = app.lower{7};
            app.bkgb_limit_high.Value = app.higher{7};

            app.bkgc_startField.Value = app.fitting{8};
            app.bkgc_limit_low.Value = app.lower{8};
            app.bkgc_limit_high.Value = app.higher{8};
            
            app.amplitude_startField.Value = app.fitting{9};
            app.amplitude_limit_low.Value = app.lower{9};
            app.amplitude_limit_high.Value = app.higher{9};
            
            app.resolution_startField.Value = app.fitting{10};
            app.resolution_limit_low.Value = app.lower{10};
            app.resolution_limit_high.Value = app.higher{10};

            app.I_startField.Value = app.fitting{11};
            app.I_limit_low.Value = app.lower{11};
            app.I_limit_high.Value = app.higher{11};

            app.w_startField.Value = app.fitting{12};
            app.w_limit_low.Value = app.lower{12};
            app.w_limit_high.Value = app.higher{12};

            app.dsub_startField.Value = (2*pi)/app.fitting{13};
            app.dsub_limit_high.Value = (2*pi)/app.lower{13};
            app.dsub_limit_low.Value = (2*pi)/app.higher{13};
        end
        
        % read in a layer for the simulation
        function read_in_layer(app)
            % 1: Name
            app.NameEditField_Simulate.Value = app.layers{1};
            % 2: Z
            zdouble = app.layers{2};
            zstring = string(zdouble(1));
            if length(zdouble) >= 2
                for i = 2:length(zdouble)
                    zstring = zstring + ";" + zdouble(i);
                end
            end
            app.ZEditField.Value = zstring;
            % 3: composition
            compdouble = app.layers{3};
            compstring = string(compdouble(1));
            if length(compdouble) >= 2
                for i = 2:length(compdouble)
                    compstring = compstring + ";" + compdouble(i);
                end
            end
            app.compatEditField.Value = compstring;
            % 4: d
            app.SpacingEditField.Value = app.layers{4};
            % 5: t
            app.thicknessEditField.Value = app.layers{5};
            % 6: substrate, 7: I, 8: w, 9: x
            if strcmp(app.layers{6},'NoSub')
                app.SubstrateSwitch.Value = 'No';
                app.layers{7} = 0;
                app.layers{8} = 0;
                app.layers{9} = 0;
            elseif strcmp(app.layers{6},'Sub')
                app.SubstrateSwitch.Value = 'Yes';
                app.IcpsEditField.Value = app.layers{7};
                app.dsubEditField.Value = app.layers{8};
                app.dsubEditField.Value = app.layers{9};
            end
            include_substrate(app);
            % 10: Strain
            test_strain = (app.strained == [0,0]);
            if test_strain(1) + test_strain(2) == 2
                app.StrainedSwitch.Value = 'No';
                set(app.ButtonGroupStrain_Simulate, 'Enable', 'off');
                set(app.CheckBox, 'Enable', 'off');
                set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'off');
                set(app.CheckBox_2, 'Enable', 'off');
            else
                set(app.ButtonGroupStrain_Simulate, 'Enable', 'on');
                set(app.CheckBox, 'Enable', 'on');
                set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'on');
                set(app.CheckBox_2, 'Enable', 'on');
                if app.strained(1) == 1
                    app.tensileButton.Value = true;
                    app.compressiveButton.Value = false;
                    app.CheckBox.Value = 1;
                    app.StrainedSwitch.Value = 'Yes';
                elseif app.strained(1) == -1   
                    app.tensileButton.Value = false;
                    app.compressiveButton.Value = true;
                    app.CheckBox.Value = 1;
                    app.StrainedSwitch.Value = 'Yes';
                end
                if app.strained(2) == 1
                    app.tensileButton_cap.Value = true;
                    app.compressiveButton_cap.Value = false;
                    app.CheckBox_2.Value = 1;
                    app.StrainedSwitch.Value = 'Yes';
                elseif app.strained(2) == -1   
                    app.tensileButton_cap.Value = false;
                    app.compressiveButton_cap.Value = true;
                    app.CheckBox_2.Value = 1;
                    app.StrainedSwitch.Value = 'Yes';
                end
            end

            % 11: rougness
            if strcmp(app.layers{11}, 'NoRough')
                app.RoughnessSwitch.Value = 'No';
            elseif strcmp(app.layers{11}, 'Rough')
                app.RoughnessSwitch.Value = 'Yes';
            end
            % 12: Debye-Waller pre-factor
            app.dwEditField_Simulate.Value = app.layers{12};
            % 13: density
            app.densityEditField.Value = app.layers{13};
            
        end
        
        % create array with all fitting information
        function create_S_struct(app)
            % [d, alpha_1, bkgLow, bkgHigh, N0, resolution, scale factor, alpha_2, bkgHighc, sigma, Ilorentz, wlorentz, xlorentz]
            
            % create start values
            app.GKP_bestmem = [app.fitting{1}, app.fitting{3}, app.fitting{6}, app.fitting{7}, app.fitting{2}, app.fitting{10}, app.fitting{9}, app.fitting{4}, app.fitting{8}, app.fitting{5}, app.fitting{11}, app.fitting{12}, app.fitting{13}];

            % create bounds
            app.FVr_minbound = [app.lower{1}, app.lower{3}, app.lower{6}, app.lower{7}, app.lower{2}, app.lower{10}, app.lower{9}, app.lower{4}, app.lower{8}, app.lower{5}, app.lower{11}, app.lower{12}, app.lower{13}];
            app.FVr_maxbound = [app.higher{1}, app.higher{3}, app.higher{6}, app.higher{7}, app.higher{2}, app.higher{10}, app.higher{9}, app.higher{4}, app.higher{8}, app.higher{5}, app.higher{11}, app.higher{12}, app.higher{13}];
            
            % check which parameters are supposed to be fitted
            % 1: d
            if app.dCheckBox.Value == 0
                app.FVr_minbound(1) = app.GKP_bestmem(1);
                app.FVr_maxbound(1) = app.GKP_bestmem(1);
            end

            % 5: N
            if app.NCheckBox.Value == 0
                app.FVr_minbound(5) = app.GKP_bestmem(5);
                app.FVr_maxbound(5) = app.GKP_bestmem(5);
            end

            % 2: alpha 1
            if app.alpha_1CheckBox.Value == 0
                app.FVr_minbound(2) = app.GKP_bestmem(2);
                app.FVr_maxbound(2) = app.GKP_bestmem(2);
            end

            % 8: alpha 2
            if app.alpha_2CheckBox.Value == 0
                app.FVr_minbound(8) = app.GKP_bestmem(8);
                app.FVr_maxbound(8) = app.GKP_bestmem(8);
            end

            % 10: sigma
            if app.sigmaCheckBox.Value == 0
                app.FVr_minbound(10) = app.GKP_bestmem(10);
                app.FVr_maxbound(10) = app.GKP_bestmem(10);
            end

            % 3: a
            if app.aCheckBox.Value == 0
                app.FVr_minbound(3) = app.GKP_bestmem(3);
                app.FVr_maxbound(3) = app.GKP_bestmem(3);
            end

            % 4: b
            if app.bCheckBox.Value == 0
                app.FVr_minbound(4) = app.GKP_bestmem(4);
                app.FVr_maxbound(4) = app.GKP_bestmem(4);
            end

            % 9: c
            if app.cCheckBox.Value == 0
                app.FVr_minbound(9) = app.GKP_bestmem(9);
                app.FVr_maxbound(9) = app.GKP_bestmem(9);
            end

            % 7: scale factor
            if app.scalefactCheckBox.Value == 0
                app.FVr_minbound(7) = app.GKP_bestmem(7);
                app.FVr_maxbound(7) = app.GKP_bestmem(7);
            end

            % 6: resolution
            if app.resolutionCheckBox.Value == 0
                app.FVr_minbound(6) = app.GKP_bestmem(6);
                app.FVr_maxbound(6) = app.GKP_bestmem(6);
            end

            % 11: I
            if app.I_subCheckBox.Value == 0
                app.FVr_minbound(11) = app.GKP_bestmem(11);
                app.FVr_maxbound(11) = app.GKP_bestmem(11);
            end

            % 12: w
            if app.w_subCheckBox.Value == 0
                app.FVr_minbound(12) = app.GKP_bestmem(12);
                app.FVr_maxbound(12) = app.GKP_bestmem(12);
            end

            % 13: dsub
            if app.dsubCheckBox.Value == 0
                app.FVr_minbound(13) = app.GKP_bestmem(13);
                app.FVr_maxbound(13) = app.GKP_bestmem(13);
            end

            % data range
            X1 = app.data(app.data(:,1)>=(app.x_startEditField_Fit.Value),1);
            X1 = X1(X1(:,1)<=(app.x_endEditField_Fit.Value));
            app.Y1 = app.data(app.data(:,1)>=(app.x_startEditField_Fit.Value),2);
            app.Y1 = app.Y1(1:length(X1)); % intensity data

            % calculate Q or 2 Theta
            if strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, '2 Theta')
                app.twotheta = X1;
                app.Q = 4*pi/app.WavelengthEditField_Import.Value*sind(app.twotheta/2);
            elseif strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, 'Q')
                app.Q = X1;
                app.twotheta = asind(app.Q*app.WavelengthEditField_Import.Value/(4*pi));
            end

            % error bars on data
            if strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text,'counts')
                app.Y1 = app.Y1/app.Time_sEditField.Value; % Y1 should be in counts per second
            end
            app.uY1 = sqrt(app.Y1*app.Time_sEditField.Value)/app.Time_sEditField.Value;

            % form factor
            calculate_ff(app);

            % set all information and parameters
            Eout = app.Q;
            uI   = app.uY1;
            Iout = app.Y1;
            stop = 0;
            N = length(app.FVr_minbound);
            
            F_VTR        = stop;
            I_D          = length(app.GKP_bestmem);
            I_bnd_constr = 1;      
            I_NP         = 15*I_D;
            I_itermax    = app.MaxIterationsEditField.Value;
            F_weight     = 0.7;
            F_CR         = 0.8;
            I_strategy   = 1;
            I_refresh    = 1;
            I_plotting   = 1;
            
            n     = length(Iout);
            m     = length(app.GKP_bestmem);
            ratio = Iout./uI;
            
            app.S_struct.lambda       = app.WavelengthEditField_Import.Value;
            app.S_struct.Q            = app.Q(:);
            app.S_struct.I            = Iout(:);
            app.S_struct.L            = length(app.Q);
            app.S_struct.N            = length(app.Q);
            app.S_struct.f            = app.formfactorsQ;
            app.S_struct.uI           = uI(:);
            app.S_struct.I_NP         = I_NP;
            app.S_struct.F_weight     = F_weight;
            app.S_struct.F_CR         = F_CR;
            app.S_struct.I_D          = I_D;
            app.S_struct.FVr_minbound = app.FVr_minbound;
            app.S_struct.FVr_maxbound = app.FVr_maxbound;
            app.S_struct.I_bnd_constr = I_bnd_constr;
            app.S_struct.I_itermax    = I_itermax;
            app.S_struct.F_VTR        = F_VTR;
            app.S_struct.I_strategy   = I_strategy;
            app.S_struct.I_refresh    = I_refresh;
            app.S_struct.I_plotting   = I_plotting;
            app.S_struct.GKP_bestmem  = app.GKP_bestmem;

            % saving the plot?
            app.S_struct.saving       = 0; % 1: yes, 0: no

            % plot 0: 2theta, 1:Q
            app.S_struct.plot_unit    = 0;

            % access through GUI?
            app.S_struct.GUI          = 1; % 1: yes, 0: no
            app.S_struct.axe          = app.Plotting_Fit;
            
            % sample parameters
            app.S_struct.sample       = app.SampleNameTextArea.Value;
            app.S_struct.path         = strcat(app.path_d, '\');
            app.S_struct.theta_m      = app.theta_m.Value;
            if strcmp(app.MonochromatoronsideofSwitch.Value, 'source')
                app.S_struct.theta_mPath  = 1;
            elseif strcmp(app.MonochromatoronsideofSwitch.Value, 'detector')
                app.S_struct.theta_mPath  = -1;
            end
            app.S_struct.mu           = app.mu;

            app.S_struct.strained     = app.strained;
            
            if strcmp(app.layers{6}, 'Sub')
                app.S_struct.substrate    = 1;
            elseif strcmp(app.layers{6}, 'NoSub')
                app.S_struct.substrate    = 0;
            end

            if strcmp(app.layers{11}, 'Rough')
                app.S_struct.roughness    = 1;
            elseif strcmp(app.layers{11}, 'NoRough')
                app.S_struct.roughness    = 0;
            end
            
            if strcmp(app.BackgroundSwitch.Value, 'linear')
                app.S_struct.background   = 0;
            elseif strcmp(app.BackgroundSwitch.Value, 'polynomial')
                app.S_struct.background   = 1;
            end

            app.S_struct.dwf          = app.dwEditField_Simulate.Value;
            app.S_struct.Nmax         = app.layers{5}/app.layers{4};
        end

        % fit
        function fit_laue_oscillations(app)
            
            global flag_stopped;

            flag_stopped = 0;

            % call fitting function
            [app.p,app.S_y,app.I_nf] = deopt('objfun_laue','PlotIt_laue',app.S_struct);

            % fitting completed
            app.StartfittingButton.Text = 'Fit again.';
        end

        % display fitted values
        function display_after_fit_values(app)
            % [d, alpha_1, bkgLow, bkgHigh, N0, resolution, scale factor, alpha_2, bkgHighc, sigma, Ilorentz, wlorentz, xlorentz]
            app.fitting{1} = app.p(1);
            app.fitting{2} = app.p(5);
            app.fitting{3} = app.p(2);
            app.fitting{4} = app.p(8);
            app.fitting{5} = app.p(10);
            app.fitting{6} = app.p(3);
            app.fitting{7} = app.p(4);
            app.fitting{8} = app.p(9);
            app.fitting{9} = app.p(7);
            app.fitting{10} = app.p(6);
            app.fitting{11} = app.p(11);
            app.fitting{12} = app.p(12);
            app.fitting{13} = app.p(13);

            % display them in the fit tab
            display_fit(app);

            % make sure everything is read in
            update_flh(app);
        end

        
        function disable_fit(app)
            % disable display drop down
            set(app.DisplayDropDown, 'Enable', 'off');

            % disable maxit
            set(app.MaxIterationsEditField, 'Enable', 'off');

            % disable simulating
            set(app.SimulateButton, 'Enable', 'off');

            % disable buttons
            set(app.ExportparameterButton, 'Enable', 'off');
            set(app.ExportfitButton, 'Enable', 'off');

            % d
            set(app.dCheckBox, 'Enable', 'off');
            set(app.d_limit_low, 'Enable', 'off');
            set(app.d_limit_high, 'Enable', 'off');
            set(app.d_startField, 'Enable', 'off');
            % N
            set(app.NCheckBox, 'Enable', 'off');
            set(app.N_limit_low, 'Enable', 'off');
            set(app.N_limit_high, 'Enable', 'off');
            set(app.N_startField, 'Enable', 'off');

            % alpha1
            set(app.alpha_1CheckBox, 'Enable', 'off');
            set(app.alpha1_limit_low, 'Enable', 'off');
            set(app.alpha1_limit_high, 'Enable', 'off');
            set(app.alpha1_startField, 'Enable', 'off');
            % alpha2
            set(app.alpha_2CheckBox, 'Enable', 'off');
            set(app.alpha2_limit_low, 'Enable', 'off');
            set(app.alpha2_limit_high, 'Enable', 'off');
            set(app.alpha2_startField, 'Enable', 'off');

            % sigma
            set(app.sigmaCheckBox, 'Enable', 'off');
            set(app.sigma_limit_low, 'Enable', 'off');
            set(app.sigma_limit_high, 'Enable', 'off');
            set(app.sigma_startField, 'Enable', 'off');

            % Bkg_a
            set(app.aCheckBox, 'Enable', 'off');
            set(app.bkga_limit_low, 'Enable', 'off');
            set(app.bkga_limit_high, 'Enable', 'off');
            set(app.bkga_startField, 'Enable', 'off');
            % Bkg_b
            set(app.bCheckBox, 'Enable', 'off');
            set(app.bkgb_limit_low, 'Enable', 'off');
            set(app.bkgb_limit_high, 'Enable', 'off');
            set(app.bkgb_startField, 'Enable', 'off');

            % Bkg_c
            set(app.cCheckBox, 'Enable', 'off');
            set(app.bkgc_limit_low, 'Enable', 'off');
            set(app.bkgc_limit_high, 'Enable', 'off');
            set(app.bkgc_startField, 'Enable', 'off');

            % scale factor
            set(app.scalefactCheckBox, 'Enable', 'off');
            set(app.amplitude_limit_low, 'Enable', 'off');
            set(app.amplitude_limit_high, 'Enable', 'off');
            set(app.amplitude_startField, 'Enable', 'off');
            % resolution
            set(app.resolutionCheckBox, 'Enable', 'off');
            set(app.resolution_limit_low, 'Enable', 'off');
            set(app.resolution_limit_high, 'Enable', 'off');
            set(app.resolution_startField, 'Enable', 'off');

            % I
            set(app.I_subCheckBox, 'Enable', 'off');
            set(app.I_limit_low, 'Enable', 'off');
            set(app.I_limit_high, 'Enable', 'off');
            set(app.I_startField, 'Enable', 'off');
            % w
            set(app.w_subCheckBox, 'Enable', 'off');
            set(app.w_limit_low, 'Enable', 'off');
            set(app.w_limit_high, 'Enable', 'off');
            set(app.w_startField, 'Enable', 'off');
            % dsub
            set(app.dsubCheckBox, 'Enable', 'off');
            set(app.dsub_limit_low, 'Enable', 'off');
            set(app.dsub_limit_high, 'Enable', 'off');
            set(app.dsub_startField, 'Enable', 'off');

            % background switch
            set(app.BackgroundSwitch, 'Enable', 'off');
        end
        
        function enable_fit(app)
            % enable display drop down
            set(app.DisplayDropDown, 'Enable', 'on');

            % enable simulating
            set(app.SimulateButton, 'Enable', 'on');

            % enable maxit
            set(app.MaxIterationsEditField, 'Enable', 'on');

            % prepare fitting tab
            % d
            set(app.dCheckBox, 'Enable', 'on');
            set(app.d_limit_low, 'Enable', 'on');
            set(app.d_limit_high, 'Enable', 'on');
            set(app.d_startField, 'Enable', 'on');
            % N
            set(app.NCheckBox, 'Enable', 'on');
            set(app.N_limit_low, 'Enable', 'on');
            set(app.N_limit_high, 'Enable', 'on');
            set(app.N_startField, 'Enable', 'on');

            % alpha1
            set(app.alpha_1CheckBox, 'Enable', 'off');
            set(app.alpha1_limit_low, 'Enable', 'off');
            set(app.alpha1_limit_high, 'Enable', 'off');
            set(app.alpha1_startField, 'Enable', 'off');
            % alpha2
            set(app.alpha_2CheckBox, 'Enable', 'off');
            set(app.alpha2_limit_low, 'Enable', 'off');
            set(app.alpha2_limit_high, 'Enable', 'off');
            set(app.alpha2_startField, 'Enable', 'off');
            if ~strcmp(app.layers{10}, 'NoStrain')
                if app.strained(1) ~= 0
                    % alpha1
                    set(app.alpha_1CheckBox, 'Enable', 'on');
                    set(app.alpha1_limit_low, 'Enable', 'on');
                    set(app.alpha1_limit_high, 'Enable', 'on');
                    set(app.alpha1_startField, 'Enable', 'on');
                end
                if app.strained(2) ~= 0
                    % alpha2
                    set(app.alpha_2CheckBox, 'Enable', 'on');
                    set(app.alpha2_limit_low, 'Enable', 'on');
                    set(app.alpha2_limit_high, 'Enable', 'on');
                    set(app.alpha2_startField, 'Enable', 'on');
                end
            end

            if strcmp(app.layers{11}, 'Rough')
                % sigma
                set(app.sigmaCheckBox, 'Enable', 'on');
                set(app.sigma_limit_low, 'Enable', 'on');
                set(app.sigma_limit_high, 'Enable', 'on');
                set(app.sigma_startField, 'Enable', 'on');
            end

            % Bkg_a
            set(app.aCheckBox, 'Enable', 'on');
            set(app.bkga_limit_low, 'Enable', 'on');
            set(app.bkga_limit_high, 'Enable', 'on');
            set(app.bkga_startField, 'Enable', 'on');
            % Bkg_b
            set(app.bCheckBox, 'Enable', 'on');
            set(app.bkgb_limit_low, 'Enable', 'on');
            set(app.bkgb_limit_high, 'Enable', 'on');
            set(app.bkgb_startField, 'Enable', 'on');

            if strcmp(app.BackgroundSwitch.Value, 'polynomial')
                % Bkg_c
                set(app.cCheckBox, 'Enable', 'on');
                set(app.bkgc_limit_low, 'Enable', 'on');
                set(app.bkgc_limit_high, 'Enable', 'on');
                set(app.bkgc_startField, 'Enable', 'on');
            end

            % scale factor
            set(app.scalefactCheckBox, 'Enable', 'on');
            set(app.amplitude_limit_low, 'Enable', 'on');
            set(app.amplitude_limit_high, 'Enable', 'on');
            set(app.amplitude_startField, 'Enable', 'on');
            % resolution
            set(app.resolutionCheckBox, 'Enable', 'on');
            set(app.resolution_limit_low, 'Enable', 'on');
            set(app.resolution_limit_high, 'Enable', 'on');
            set(app.resolution_startField, 'Enable', 'on');

            if strcmp(app.layers{6}, 'Sub')
                % I
                set(app.I_subCheckBox, 'Enable', 'on');
                set(app.I_limit_low, 'Enable', 'on');
                set(app.I_limit_high, 'Enable', 'on');
                set(app.I_startField, 'Enable', 'on');
                % w
                set(app.w_subCheckBox, 'Enable', 'on');
                set(app.w_limit_low, 'Enable', 'on');
                set(app.w_limit_high, 'Enable', 'on');
                set(app.w_startField, 'Enable', 'on');
                % dsub
                set(app.dsubCheckBox, 'Enable', 'on');
                set(app.dsub_limit_low, 'Enable', 'on');
                set(app.dsub_limit_high, 'Enable', 'on');
                set(app.dsub_startField, 'Enable', 'on');
            end

            % background switch
            set(app.BackgroundSwitch, 'Enable', 'on');

            % untick all boxes
            app.dCheckBox.Value = 0;
            app.NCheckBox.Value = 0;
            app.alpha_1CheckBox.Value = 0;
            app.alpha_2CheckBox.Value = 0;
            app.sigmaCheckBox.Value = 0;
            app.aCheckBox.Value = 0;
            app.bCheckBox.Value = 0;
            app.cCheckBox.Value = 0;
            app.scalefactCheckBox.Value = 0;
            app.resolutionCheckBox.Value = 0;
            app.I_subCheckBox.Value = 0;
            app.w_subCheckBox.Value = 0;
            app.dsubCheckBox.Value = 0;
        end
        
        function replot_limits(app)
            % import tab
            app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
            
            % simulate tab
            app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];

            % fit tab
            app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];
        end
        
        % include substrate in fitting
        function include_substrate(app)
            if strcmp(app.SubstrateSwitch.Value, 'No')
                set(app.IcpsEditField, 'Enable', 'off');
                set(app.HWHMdegreesEditField, 'Enable', 'off');
                set(app.dsubEditField, 'Enable', 'off');
                app.IcpsEditField.Value = 1;
                app.HWHMdegreesEditField.Value = 0.001;
                app.dsubEditField.Value = 1;
            elseif strcmp(app.SubstrateSwitch.Value, 'Yes')
                set(app.IcpsEditField, 'Enable', 'on');
                set(app.HWHMdegreesEditField, 'Enable', 'on');
                set(app.dsubEditField, 'Enable', 'on');
            end
        end

        % simulate scattering pattern of a thin film
        function simulate_fit(app)
            % update app.fitting, app.lower, app.higher
            update_flh(app);

            % set strain & roughness
            alpha_1 = app.fitting{3};
            alpha_2 = app.fitting{4};
            sigma = app.fitting{5};

            % data range
            X1 = app.data(app.data(:,1)>=(app.x_startEditField_Fit.Value),1);
            X1 = X1(X1(:,1)<=(app.x_endEditField_Fit.Value));
            app.Y1 = app.data(app.data(:,1)>=(app.x_startEditField_Fit.Value),2);
            app.Y1 = app.Y1(1:length(X1)); % intensity data

            % calculate Q or 2 Theta
            if strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, '2 Theta')
                app.twotheta = X1;
                app.Q = 4*pi/app.WavelengthEditField_Import.Value*sind(app.twotheta/2);
            elseif strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, 'Q')
                app.Q = X1;
                app.twotheta = asind(app.Q*app.WavelengthEditField_Import.Value/(4*pi));
            end

            % error bars on data
            if strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text,'counts')
                app.Y1 = app.Y1/app.Time_sEditField.Value; % Y1 should be in counts per second
            end
            app.uY1 = sqrt(app.Y1*app.Time_sEditField.Value)/app.Time_sEditField.Value;

            % debye-waller factor
            DebyeWaller = 1*exp(app.dwEditField_Simulate.Value*(app.Q/4/pi).^2);

            % form factor
            calculate_ff(app);

            % intensity
            app.I_fit = zeros(size(app.Q));

            % nmax
            Nmax = app.fitting{2};

            % rounded number of layers
            Nlayers = round(Nmax);
        
            % calculate DWF
            DF = DebyeWaller.*app.formfactorsQ;

            % calculate the strucure factor

            % if layer roughness should be included in the fitting
            if strcmp(app.layers{11}, 'Rough') && sigma > 0
                
               % range of 3 sigma plus/minus for roughness to be included
                nvector = max(1,round(Nlayers-3*sigma)):1:max(1,round(Nlayers+3*sigma));
        
                % out-of-plane positions of the atoms
                x   = cell(size(nvector));
                % positions
                pos = cell(size(nvector));
                % including roughness
                B   = zeros(size(nvector));
                A   = 1/(sqrt(2*pi)*sigma);
        
                % strain
                strain   = cell(size(nvector));
        
        
                for N = nvector
                
                    % out-of-plane positions of the atoms
                    x{N} = ones(1,N)*app.fitting{1};
                    % position
                    p_pos      = cumsum(x{N});
                    % start position
                    pos{N} = [0 p_pos];
        
                    % including roughness
                    B(N) = A*exp(-(N-Nlayers)^2/(2*sigma^2));
                
                    % same array upside down
                    pos_ud{N} = flip(pos{N},2);
                    
                    % calculate substrate strain
                    if app.strained(1) ~=0
                        strain_bottom{N} = exp(-alpha_1*pos{N});
                        if app.strained(1) == 1
                            pos{N} = pos{N} - strain_bottom{N};
                        elseif app.strained(1) == -1
                             pos{N} = pos{N} + strain_bottom{N};
                        end
                    end
                    % calculate cap strain
                    if app.strained(2) ~=0
                        strain_top{N} = exp(-alpha_2*pos_ud{N});
                        if app.strained(2) == 1
                            pos{N} = pos{N} + strain_top{N};
                        elseif app.strained(2) == -1
                             pos{N} = pos{N} - strain_top{N};
                        end
                    end
                end
                
                % initialization structure factor
                F = zeros(size(app.Q));
            
                % calculate structure factor
                for N = nvector
                    for i =1:length(app.Q)
                        F(i) = sum(exp(1i*app.Q(i).*pos{N}));
                    end
                    app.I_fit = app.I_fit + abs(F).^2*B(N); % calculated Intensity
                end
        
                % calculate intensity
                app.I_fit = (DF).^2.*app.I_fit;

            % if layer roughness should not be included in the fitting    
            else
        
                % out-of-plane positions of the atoms
                x = ones(1,round(Nmax))*app.fitting{1};
                pos = cumsum(x);
                % start position
                pos = [0 pos];
                
                % same array upside down
                pos_ud = flip(pos,2);
                
                % calculate substrate strain
                if app.strained(1) ~= 0
                    strain_bottom = exp(-alpha_1*pos);
                    if app.strained(1) == 1
                        pos = pos - strain_bottom;
                    elseif app.strained(1) == -1
                         pos = pos + strain_bottom;
                    end
                end
                % calculate cap strain
                if app.strained(2) ~= 0
                    strain_top = exp(-alpha_2*pos_ud);
                    if app.strained(2) == 1
                        pos = pos + strain_top;
                    elseif app.strained(2) == -1
                         pos = pos - strain_top;
                    end
                end
        
                % initialization structure factor
                F = zeros(size(app.Q));
               
        
                % calculate structure factor
                for i =1:length(app.Q)
                    F(i) = DF(i)*sum(exp(1i*app.Q(i).*pos));
                end
        
                app.I_fit = abs(F).^2; % calculated Intensity
            end

            % out-of-plane positions of the atoms
            x = ones(1,round(Nmax))*app.fitting{1};
            pos = cumsum(x);
            % start position
            pos = [0 pos];
            
            % same array upside down
            pos_ud = flip(pos,2);
            
            % calculate substrate strain
            if app.strained(1) ~= 0
                strain_bottom = exp(-alpha_1*pos);
                if app.strained(1) == 1
                    pos = pos - strain_bottom;
                elseif app.strained(1) == -1
                     pos = pos + strain_bottom;
                end
            end
            % calculate cap strain
            if app.strained(2) ~= 0
                strain_top = exp(-alpha_2*pos_ud);
                if app.strained(2) == 1
                    pos = pos + strain_top;
                elseif app.strained(2) == -1
                     pos = pos - strain_top;
                end
            end
            
            % calculate the scattering
            theta = app.twotheta./2;
            tau = pos(end)*1e-10; % film thickness in m
            
            % calculate the intensity
            % optics on incident side
            if strcmp(app.MonochromatoronsideofSwitch.Value, 'source')
                % with linear background a*Q+b
                if app.fitting{8} == 0
                    app.I_fit    = app.fitting{9}*GaussConv(app.Q,app.I_fit,app.fitting{10}).* (1-exp(-2*app.mu*tau./sind(theta))).* ((1+cosd(2*app.theta_m.Value)^2.*cosd(2*theta).^2)./(1+cosd(2*app.theta_m.Value).^2)).* 1./(sind(2*theta));
                    app.I_fit    = app.I_fit + app.fitting{6}*app.Q+app.fitting{7};
                % with polynomial background c*(a+Q).^2+b
                else
                    app.I_fit    = app.fitting{9}*GaussConv(app.Q,app.I_fit,app.fitting{10}).* (1-exp(-2*app.mu*tau./sind(theta))).* (1+cosd(2*app.theta_m.Value)^2.*cosd(2*theta).^2)./(1+cosd(2*app.theta_m.Value).^2).* 1./(sind(2*theta)) + app.fitting{8}*(app.fitting{6}+app.Q).^2+app.fitting{7};
                end

            % optics on detector side
            elseif strcmp(app.MonochromatoronsideofSwitch.Value, 'detector')
                % with linear background a*Q+b
                if app.fitting{8} == 0
                    app.I_fit    = app.fitting{9}*GaussConv(app.Q,app.I_fit,app.fitting{10}).* (1-exp(-2*app.mu*tau./sind(theta))).* 0.5*(1+cosd(2*theta).^2*cosd(2*app.theta_m.Value).^2).* 1./(sind(2*theta));
                    app.I_fit    = app.I_fit + app.fitting{6}*app.Q+app.fitting{7};
                % with polynomial background c*(a+Q).^2+b
                else
                    app.I_fit    = app.fitting{9}*GaussConv(app.Q,app.I_fit,app.fitting{10}).* (1-exp(-2*app.mu*tau./sind(theta))).* 0.5*(1+cosd(2*theta).^2*cosd(2*app.theta_m.Value).^2).* 1./(sind(2*theta)) + app.fitting{8}*(app.fitting{6}+app.Q).^2+app.fitting{7};
                end
            end
                
            % including scattering from the substrate
            if app.fitting{11} ~= 0
                x_lor_Q   = app.fitting{13};
                a_lor     = [app.fitting{11} app.fitting{12} x_lor_Q];
                I_lor     = lorentz(a_lor,app.Q);
                app.I_fit     = I_lor + app.I_fit;
            end
        end
        
        function plot_in_fit_tab(app)
            % plot fit
            if strcmp(app.DisplayDropDown.Value, 'Fitting')

                % change back to fitting: Y axis
                if strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'counts')
                        app.Plotting_Fit.YLabel.String = 'Intensity [counts]';
                elseif strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'cps') 
                    app.Plotting_Fit.YLabel.String = 'Intensity [cps]';
                end
                app.Plotting_Fit.YScale = 'log';
                app.Plotting_Fit.YLim = [-Inf Inf];
                grid(app.Plotting_Fit, 'off');

                % change back to fitting: X axis
                if strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, '2 Theta')
                    app.Plotting_Fit.XLabel.String = '2 Theta [degrees]';
                elseif strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, 'Q')
                    app.Plotting_Fit.XLabel.String = 'Q [1/Å]';
                end
                app.Plotting_Fit.XTickMode = 'auto';
                app.Plotting_Fit.XTickLabelMode = 'auto';
                app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];

                % if a simulation was done last
                if strcmp(app.last, 'sim')
                    % Intensity units
                    if strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'counts')
                        Y1_plot = app.Y1 * app.Time_sEditField.Value;
                    elseif strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'cps')
                        Y1_plot = app.Y1;
                    end

                    % plot it
                    if strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, '2 Theta')
                        cla(app.Plotting_Fit);
                        hold(app.Plotting_Fit,'on');
                        plot(app.Plotting_Fit, app.twotheta, Y1_plot, 'Color', 'k');
                        plot(app.Plotting_Fit, app.twotheta, app.I_fit, '-r','LineWidth',1.5);
                        hold(app.Plotting_Fit,'off');
                        app.Plotting_Fit.YScale = 'log';
                        app.Plotting_Fit.XLimMode = 'manual';
                        app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];
                                                
                    elseif strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, 'Q')
                        cla(app.Plotting_Fit);
                        hold(app.Plotting_Fit,'on');
                        plot(app.Plotting_Fit, app.Q, Y1_plot, 'Color', 'k');
                        plot(app.Plotting_Fit, app.Q, app.I_fit, '-r','LineWidth',1.5);
                        hold(app.Plotting_Fit,'off');
                        app.Plotting_Fit.YScale = 'log';
                        app.Plotting_Fit.XLimMode = 'manual';
                        app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];
                    end
                
                % if a fit was done last    
                elseif strcmp(app.last, 'fit')
                    % update parameters
                    update_flh(app);

                    % create S_struct again
                    create_S_struct(app);

                    % reset some properties
                    app.Plotting_Fit.YLim = [-Inf Inf];
                    grid(app.Plotting_Fit, 'off');
                    app.Plotting_Fit.XTickMode = 'auto';
                    app.Plotting_Fit.XTickLabelMode = 'auto';

                    % use PlotIt_laue to plot again
                    PlotIt_laue(app.p,1,app.S_struct);

                    % reset boundaries
                    app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];
                else
                    errorbar([], [], [], 'parent', app.Plotting_Fit);
                end

            % plot parameters
            elseif strcmp(app.DisplayDropDown.Value, 'Parameters')
                % update parameters
                update_flh(app);

                if app.fitting{1} ~= 0                  
                    cla(app.Plotting_Fit);
                    hold(app.Plotting_Fit,'on');
                    for i = 1:length(app.fitting)
                        scatter(i,(app.fitting{i}-app.lower{i})/(app.higher{i}-app.lower{i}), 'parent', app.Plotting_Fit, 150,'filled', 'r');
                    end
                    hold(app.Plotting_Fit,'off');
                    app.Plotting_Fit.YScale = 'linear';
                    app.Plotting_Fit.XLabel.String = 'Fitting parameters';
                    app.Plotting_Fit.YLabel.String = 'Normalized to parameter bound';
                    app.Plotting_Fit.XLimMode = 'manual';
                    app.Plotting_Fit.XTick = linspace(1,length(app.fitting),length(app.fitting));
                    app.Plotting_Fit.XTickLabel = {'d', 'N0', 'alpha1', 'alpha2', 'sigma', 'a', 'b', 'c', 'scale fact', 'res', 'I', 'w', 'x'};
                    app.Plotting_Fit.XLim = [0, length(app.fitting)+1];
                    app.Plotting_Fit.YLim = [0, 1];
                    grid(app.Plotting_Fit, 'on');
                end
            elseif strcmp(app.DisplayDropDown.Value, 'Figure of merit')
                % get gof
                global gofs;
                
                % display axes
                cla(app.Plotting_Fit);
                app.Plotting_Fit.YScale = 'linear';
                app.Plotting_Fit.XLabel.String = 'Iterations';
                app.Plotting_Fit.YLabel.String = 'Cost function evaluation';
                app.Plotting_Fit.XLimMode = 'auto';
                app.Plotting_Fit.YLimMode = 'auto';
                app.Plotting_Fit.XTickMode = 'auto';
                app.Plotting_Fit.XTickLabelMode = 'auto';
                grid(app.Plotting_Fit, 'off');
                
                if length(gofs) ~= 1
                    hold(app.Plotting_Fit,'on');
                    iterats = linspace(2,length(gofs),length(gofs)-1);
                    plot(app.Plotting_Fit, iterats, gofs(2:end), '-r','LineWidth',1.5);
                    hold(app.Plotting_Fit,'off');
                end

            % plot parameters
            elseif strcmp(app.DisplayDropDown.Value, 'Strain profile')
                
                % create stack of atoms
                x = ones(1,round(app.fitting{2}))*app.fitting{1};
                pos = cumsum(x);
                pos = [0 pos];

                % same array upside down
                pos_ud = flip(pos,2);

                display(app.strained);
                
                % calculate substrate strain
                if app.strained(1) ~= 0
                    strain_bottom = exp(-app.fitting{3}*pos);
                    if app.strained(1) == 1
                        pos = pos - strain_bottom;
                    elseif app.strained(1) == -1
                         pos = pos + strain_bottom;
                    end
                end
                % calculate cap strain
                if app.strained(2) ~= 0
                    strain_top = exp(-app.fitting{4}*pos_ud);
                    if app.strained(2) == 1
                        pos = pos + strain_top;
                    elseif app.strained(2) == -1
                         pos = pos - strain_top;
                    end
                end
                                       
                dspacings = diff(pos); % array of d spacings for each atom
                n = [2:length(dspacings)+1]; % number of layers

                % display axes
                cla(app.Plotting_Fit);
                app.Plotting_Fit.YScale = 'linear';
                app.Plotting_Fit.XLabel.String = 'n';
                app.Plotting_Fit.YLabel.String = 'd [Å]';
                app.Plotting_Fit.XLimMode = 'auto';
                app.Plotting_Fit.YLimMode = 'auto';
                app.Plotting_Fit.XTickMode = 'auto';
                app.Plotting_Fit.XTickLabelMode = 'auto';
                grid(app.Plotting_Fit, 'off');
                
                % plot it
                hold(app.Plotting_Fit,'on');
                plot(app.Plotting_Fit, n(2:end) ,dspacings(2:end), '-r','LineWidth',1.5); % plot evolution of d
                hold(app.Plotting_Fit,'off');
            end
        end
        

        % update app.fitting, app.lower and app.higher
        function update_flh(app)
            % d
            app.fitting{1} = app.d_startField.Value;
            app.lower{1} = app.d_limit_low.Value;
            app.higher{1} = app.d_limit_high.Value;
            
            % N
            app.fitting{2} = app.N_startField.Value;
            app.lower{2} = app.N_limit_low.Value;
            app.higher{2} = app.N_limit_high.Value;
            
            % alpha 1
            app.fitting{3} = app.alpha1_startField.Value;
            app.lower{3} = app.alpha1_limit_low.Value;
            app.higher{3} = app.alpha1_limit_high.Value;

            % alpha 2
            app.fitting{4} = app.alpha2_startField.Value;
            app.lower{4} = app.alpha2_limit_low.Value;
            app.higher{4} = app.alpha2_limit_high.Value;

            % sigma
            app.fitting{5} = app.sigma_startField.Value;
            app.lower{5} = app.sigma_limit_low.Value;
            app.higher{5} = app.sigma_limit_high.Value;

            % a
            app.fitting{6} = app.bkga_startField.Value;
            app.lower{6} = app.bkga_limit_low.Value;
            app.higher{6} = app.bkga_limit_high.Value;

            % b
            app.fitting{7} = app.bkgb_startField.Value;
            app.lower{7} = app.bkgb_limit_low.Value;
            app.higher{7} = app.bkgb_limit_high.Value;

            % c
            app.fitting{8} = app.bkgc_startField.Value;
            app.lower{8} = app.bkgc_limit_low.Value;
            app.higher{8} = app.bkgc_limit_high.Value;

            % scale factor
            app.fitting{9} = app.amplitude_startField.Value;
            app.lower{9} = app.amplitude_limit_low.Value;
            app.higher{9} = app.amplitude_limit_high.Value;

            % resolution
            app.fitting{10} = app.resolution_startField.Value;
            app.lower{10} = app.resolution_limit_low.Value;
            app.higher{10} = app.resolution_limit_high.Value;

            % I
            app.fitting{11} = app.I_startField.Value;
            app.lower{11} = app.I_limit_low.Value;
            app.higher{11} = app.I_limit_high.Value;

            % w
            app.fitting{12} = app.w_startField.Value;
            app.lower{12} = app.w_limit_low.Value;
            app.higher{12} = app.w_limit_high.Value;

            % dsub
            app.fitting{13} = (2*pi)/app.dsub_startField.Value;
            app.lower{13} = (2*pi)/app.dsub_limit_high.Value;
            app.higher{13} = (2*pi)/app.dsub_limit_low.Value;
        end
        
        % download fitted parameters and upper and lower bounds
        function download_parameters(app)
            % read in paramters
            update_flh(app);

            % select path and file
            while true
                [file,path] = uiputfile('*.txt');
                app.datadownload = [path file];
                if file == 0                            % for "Cancel"
                    app.ExportparameterButton.Text = 'Try export again.';
                    break;
                else                                    % for "Open"
                    app.ExportparameterButton.Text = 'Parameters exported'; 

                    % create cell array
                    lower_temp = zeros(length(app.fitting),1);
                    higher_temp = zeros(length(app.fitting),1);
                    fitting_temp = zeros(length(app.fitting),1);
                    for i = 1:length(app.fitting)
                        lower_temp(i) = app.lower{i};
                        higher_temp(i) = app.higher{i};
                        fitting_temp(i) = app.fitting{i};
                    end
                       
                    % put data in array
                    dataarray = zeros(length(app.fitting), 4);
                    dataarray(:,1) = linspace(1,length(app.fitting), length(app.fitting));
                    dataarray(:,2) = lower_temp;
                    dataarray(:,3) = fitting_temp;
                    dataarray(:,4) = higher_temp;
                    
                    % add heading
                    global gofs;
                    fid = fopen(app.datadownload, 'w');
                    fprintf(fid, 'd   N   alpha_1   alpha_2   sigma   a   b   c   scale factor    resolution   I   w   dsub\n\n');
                    fprintf(fid, 'parameter, lowerb, fitted, upperb\n');
                    for i = 1:length(dataarray(:,1))
                        fprintf(fid, '%d    %f    %f    %f\n', dataarray(i,1), dataarray(i,2), dataarray(i,3), dataarray(i,4));
                    end
                    fprintf(fid, '\n');
                    fprintf(fid, 'Fitting cost function evaluation: %f\n', gofs(end));
                    fclose(fid);
                    break;
                end
            end
        end

        % download data and fit
        function download_fit(app)
            % simulate
            SimulateButtonPushed(app);

            % select path and file
            while true
                [file,path] = uiputfile('*.txt');
                app.datadownload = [path file];
                if file == 0                            % for "Cancel"
                    app.ExportfitButton.Text = 'Try export again.';
                    break;
                else                                    % for "Open"
                    app.ExportfitButton.Text = 'Fit exported'; 
                       
                    % put data in array
                    dataarray = zeros(length(app.Q), 4);
                    dataarray(:,1) = app.twotheta;
                    dataarray(:,2) = app.Q;
                    dataarray(:,3) = app.Y1;
                    dataarray(:,4) = app.I_fit;
                    
                    % add heading
                    fid = fopen(app.datadownload, 'w');
                    fprintf(fid, '2Theta [deg], Q [Å^-1], Data: I [cps], Fit: I [cps]\n');
                    for i = 1:length(dataarray(:,1))
                        fprintf(fid, '%f   %f    %f    %f\n', dataarray(i,1), dataarray(i,2), dataarray(i,3), dataarray(i,4));
                    end
                    fclose(fid);
                    break;
                end
            end
        end
        
        % check whether window size is too large for computing
        function works = check_size(app) % 1: if it works, 0: if it doesnt
            % calculate how big it would be
            X1 = app.data(app.data(:,1)>=(app.x_startEditField_Fit.Value),1);
            X1 = X1(X1(:,1)<=(app.x_endEditField_Fit.Value));

            % if too large
            if length(X1) >= 5000
                works = 0;
                msg = 'This 2Theta/Q range is too large for computing. Please select a smaller one.';
                title = 'Range is too wide!';
                selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Got it!'}, 'DefaultOption',1,'CancelOption',1,'Icon','warning');
                switch selection
                case 'Got it!'
                    return
                end
            else
                works = 1;
            end
        end

        % check whether at least one parameter is to be fitted
        function works = check_fit(app)
            % check if at least 1 parameter selected
            sum = app.dCheckBox.Value+app.NCheckBox.Value+app.alpha_1CheckBox.Value+app.alpha_2CheckBox.Value+app.sigmaCheckBox.Value+app.aCheckBox.Value+app.bCheckBox.Value+app.scalefactCheckBox.Value+app.resolutionCheckBox.Value+app.I_subCheckBox.Value+app.w_subCheckBox.Value+app.dsubCheckBox.Value;
            if sum == 0
                works = 0;
            else
                works = 1;
            end
    
            % check if in bounds
            redflag = 0;
            for i = 1:length(app.lower)
                if ~(app.lower{i} <= app.fitting{i} && app.higher{i} >= app.fitting{i})
                    redflag = redflag + 1;
                end
            end
            if redflag ~= 0
                works = 0;
            end
        end
        
        % export the strain profile
        function download_strain_profile(app)
            % select path and file
            strain_data = erase(app.datadownload, '.txt');
            strain_data = strcat(strain_data, '_strain.txt');
                       
             % create stack of atoms
            x = ones(1,round(app.fitting{2}))*app.fitting{1};
            pos = cumsum(x);
            pos = [0 pos];
            
            if strcmp(app.layers{10}, 'tensile')
                strain = exp(-app.fitting{3}*pos)+exp(-app.fitting{4}*pos);
                pos = pos - strain;
            elseif strcmp(app.layers{10}, 'compressive')
                strain = exp(-app.fitting{3}*pos)+exp(-app.fitting{4}*pos);
                pos = pos + strain;
            end
            
            dspacings = diff(pos); % array of d spacings for each atom
            n = [2:length(dspacings)+1]; % number of layers

            % add heading
            fid = fopen(strain_data, 'w');
            fprintf(fid, 'n, d[Å]\n');
            for i = 1:length(dspacings)
                fprintf(fid, '%f   %f \n', n(i), dspacings(i));
            end
            fclose(fid);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            try
            % Import
            if strcmp(app.ImportButton.Text, 'Import')
                
                % import data
                app.data = load(app.filename);

                % get rid of 0 intensity
                for k = 1:length(app.data(:,2))
                    if app.data(k,2) == 0
                        app.data(k,2) = 0.1;
                    end
                end              

                % calculate error bar
                if strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'counts')
                   app.uY1 = sqrt(app.data(:,2));
                   app.Plotting_Import.YLabel.String = 'I [counts]';
                   app.cpsButton_Plot.Value = 0;
                   app.countsButton_Plot.Value = 1;
                elseif strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text,'cps')
                   app.uY1 = sqrt(app.data(:,2)*str2double(app.Time_sEditField.Value))/str2double(app.Time_sEditField.Value);
                   app.Plotting_Import.YLabel.String = 'I [cps]';
                   app.cpsButton_Plot.Value = 1;
                   app.countsButton_Plot.Value = 0;
                end
                if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                   app.Plotting_Import.XLabel.String = '2 Theta [degrees]';
                    app.ThetaButton_Plot.Value = 1;
                   app.QButton_Plot.Value = 0;
                elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text,'Q')
                    app.Plotting_Import.XLabel.String = 'Q [1/Å]';
                     app.ThetaButton_Plot.Value = 0;
                   app.QButton_Plot.Value = 1;
                end
                
                % calculate Q or 2 Theta
                if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                    app.twotheta_data = app.data(:,1);
                    app.Q_data = 4*pi/app.WavelengthEditField_Import.Value*sind(app.twotheta_data/2);
                elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, 'Q')
                    app.Q_data = app.data(:,1);
                    app.twotheta_data = asind(app.Q_data*app.WavelengthEditField_Import.Value/(4*pi));
                end
                
                % plot it
                if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                    errorbar(app.twotheta_data, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'auto';
                    
                    % change other tabs accordingly
                    app.ThetaButton_Simulate.Value = 1;
                    app.QButton_Simulate.Value = 0;
                    app.Plotting_Simulate.XLabel.String = '2 Theta [degrees]';
                    app.ThetaButton_Plot_Fit.Value = 1;
                    app.QButton_Plot_Fit.Value = 0;
                    app.Plotting_Fit.XLabel.String = '2 Theta [degrees]';
                    
                elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, 'Q')
                    errorbar(app.Q_data, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'auto';

                    % change other tabs accordingly
                    app.ThetaButton_Simulate.Value = 0;
                    app.QButton_Simulate.Value = 1;
                    app.Plotting_Simulate.XLabel.String = 'Q [1/Å]';
                    app.ThetaButton_Plot_Fit.Value = 0;
                    app.QButton_Plot_Fit.Value = 1;
                    app.Plotting_Fit.XLabel.String = 'Q [1/Å]';

                end

                % change start and end
                app.x_startEditField_Import.Value = app.Plotting_Import.XLim(1);
                app.x_endEditField_Import.Value = app.Plotting_Import.XLim(2);
                x_startEditField_ImportValueChanged(app,event);
                
                % enable try again
                app.ImportButton.Text = 'Try again.';

                % prepare the import and simulate tabs, reset fit tab
                prepare_Imported(app);
                prepare_Simulate(app);
                clear_Fit(app);
                
            % Try again    
            elseif strcmp(app.ImportButton.Text, 'Try again.')
                % change the tabs accordingly
                if app.fitting{1} ~= 0
                    msg = 'Do you want to keep your fitting?';
                    title = 'Same fitting for this sample?';
                    selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Keep it','Reset it', 'Cancel'}, 'DefaultOption',1,'CancelOption',3,'Icon','question');
                    switch selection
                    case 'Keep it'
                        % save current data from simulate tab
                        temp_layer = app.layers;
    
                        % save current data from fit tab
                        temp_lower = app.lower;
                        temp_higher = app.higher;
                        temp_fit = app.fitting;
    
                        % prepare the simulate tab
                        prepare_Simulate(app);
    
                        % get simulation back
                        app.layers = temp_layer;
                        read_in_layer(app);
    
                        % prepare the fit tab again
                        prepare_Fit(app);
    
                        % set values to former ones
                        app.fitting = temp_fit;
                        app.lower = temp_lower;
                        app.higher = temp_higher;
                    case 'Reset it'
                        % Clear everything
                        reset_Simulate(app);
                        clear_Simulate(app);
                        clear_Fit(app);
                    case 'Cancel'
                        return
                    end
                end

                % enable import
                app.ImportButton.Text = 'Re-Import';
                
                % disable download
                set(app.ExportdataButton, 'Enable', 'off');
                
                % enable import functions
                set(app.SampleidentifierEditField, 'Enable', 'on');
                set(app.LoaddataButton, 'Enable', 'on');
                set(app.ButtonGroupUnit_Import, 'Enable', 'on');
                set(app.Time_sEditField, 'Enable', 'on');
                set(app.ButtonGroupSpace_Import, 'Enable', 'on');
                set(app.WavelengthEditField_Import, 'Enable', 'on');
                set(app.x_startEditField_Import, 'Enable', 'off');
                set(app.x_endEditField_Import, 'Enable', 'off');
                
                % disable change of unit
                set(app.ButtonGroupSpacePlot_Import, 'Enable', 'off');
                set(app.ButtonGroupUnitPlot_Import, 'Enable', 'off');
                set(app.PlotLabel, 'Enable', 'off');
                
                % disable going to the next tab
                set(app.NextButton_Import, 'Enable', 'off');

            % Re-Import
            elseif strcmp(app.ImportButton.Text, 'Re-Import')    
                % import data
                app.data = load(app.filename);

                % get rid of 0 intensity
                for k = 1:length(app.data(:,2))
                    if app.data(k,2) == 0
                        app.data(k,2) = 0.1;
                    end
                end  
                
                % calculate error bar
                if strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'counts')
                   app.uY1 = sqrt(app.data(:,2));
                   app.Plotting_Import.YLabel.String = 'I [counts]';
                   app.cpsButton_Plot.Value = 0;
                   app.countsButton_Plot.Value = 1;
                elseif strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text,'cps')
                   app.uY1 = sqrt(app.data(:,2)*str2double(app.Time_sEditField.Value))/str2double(app.Time_sEditField.Value);
                   app.Plotting_Import.YLabel.String = 'I [cps]';
                   app.cpsButton_Plot.Value = 1;
                   app.countsButton_Plot.Value = 0;
                end
                if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                   app.Plotting_Import.XLabel.String = '2 Theta [degrees]';
                   app.ThetaButton_Plot.Value = 1;
                   app.QButton_Plot.Value = 0;
                elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text,'Q')
                    app.Plotting_Import.XLabel.String = 'Q [1/Å]';
                    app.ThetaButton_Plot.Value = 0;
                   app.QButton_Plot.Value = 1;
                end
                
                % calculate Q or 2 Theta
                if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                    app.twotheta = app.data(:,1);
                    app.Q = 4*pi/app.WavelengthEditField_Import.Value*sind(app.twotheta/2);
                elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, 'Q')
                    app.Q = app.data(:,1);
                    app.twotheta = asind(app.Q*app.WavelengthEditField_Import.Value/(4*pi));
                end
                
                % plot it
                if strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, '2 Theta')
                    errorbar(app.twotheta, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                    % change other tabs accordingly
                    app.ThetaButton_Simulate.Value = 1;
                    app.QButton_Simulate.Value = 0;
                    app.Plotting_Simulate.XLabel.String  = '2 Theta [degrees]';
                    app.ThetaButton_Plot_Fit.Value = 1;
                    app.QButton_Plot_Fit.Value = 0;
                    app.Plotting_Fit.XLabel.String  = '2 Theta [degrees]';
                elseif strcmp(app.ButtonGroupSpace_Import.SelectedObject.Text, 'Q')
                    errorbar(app.Q, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                    % change other tabs accordingly
                    app.ThetaButton_Simulate.Value = 0;
                    app.QButton_Simulate.Value = 1;
                    app.Plotting_Simulate.XLabel.String = 'Q [1/Å]';
                    app.ThetaButton_Plot_Fit.Value = 0;
                    app.QButton_Plot_Fit.Value = 1;
                    app.Plotting_Fit.XLabel.String  = 'Q [1/Å]';
                    % change start and end
                    app.x_startEditField_Import.Value = 1;
                    app.x_endEditField_Import.Value = 5;
                    x_startEditField_ImportValueChanged(app,event);
                    x_endEditField_ImportValueChanged(app,event);
                end
                
                 % enable try again
                app.ImportButton.Text = 'Try again.';

                % prepare
                prepare_Imported(app);
                prepare_Simulate(app);
            end 

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            try
            % select path and file
            while true
                [app.file_d,app.path_d] = uigetfile('*.txt');
                app.GenLversion10UIFigure.Visible = 'on';
                app.filename = [app.path_d app.file_d];
                if app.file_d == 0                            % for "Cancel"
                    app.LoaddataButton.Text = 'Try select again.';
                    set(app.ImportButton, 'Enable', 'off');
                    break;
                else                                    % for "Open"
                    set(app.ImportButton, 'Enable', 'on');
                    app.LoaddataButton.Text = 'File selected.';
                    break;
                end
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            try
            % clear tabs
            clear_Import(app);
            clear_Simulate(app);
            clear_Fit(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            try
            % select path and file
            while true
                [file,path] = uiputfile('*.txt');
                app.datadownload = [path file];
                if file == 0                            % for "Cancel"
                    app.ExportdataButton.Text = 'Try export again.';
                    break;
                else                                    % for "Open"
                    app.ExportdataButton.Text = 'Exported';

                    % put data in array
                    dataarray = zeros(length(app.Q_data), 3);
                    dataarray(:,1) = app.twotheta_data;
                    dataarray(:,2) = app.Q_data;
                    dataarray(:,3) = app.data(:,2);
                    
                    % add heading
                    fid = fopen(app.datadownload, 'w');
                        if strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'counts')
                            fprintf(fid, '2Theta [deg], Q [Å^-1], I [counts]\n');
                        elseif strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'cps')
                            fprintf(fid, '2Theta [deg], Q [Å^-1], I [cps]\n');
                        end
                        for i = 1:length(dataarray(:,1))
                            fprintf(fid, '%f    %f    %f\n', dataarray(i,1), dataarray(i,2), dataarray(i,3));
                        end
                    fclose(fid);
                    break;
                end
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Selection changed function: ButtonGroupUnitPlot_Import
        function ButtonGroupUnitPlot_ImportSelectionChanged(app, event)
            try
            % change the units of the y-axis in the plot based on selection
            % and re-plot
            if strcmp(app.ButtonGroupUnitPlot_Import.SelectedObject.Text, 'counts') && strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'cps')
                app.Plotting_Import.YLabel.String = 'Intensity [counts]';
                if strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, '2 Theta')
                    errorbar(app.twotheta_data, app.data(:,2).*app.Time_sEditField.Value, app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                elseif strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, 'Q')
                    app.Plotting_Import.YLabel.String = 'Intensity [cps]';
                    errorbar(app.Q_data, app.data(:,2).*app.Time_sEditField.Value, app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                end

            elseif strcmp(app.ButtonGroupUnitPlot_Import.SelectedObject.Text, 'cps') && strcmp(app.ButtonGroupUnit_Import.SelectedObject.Text, 'counts')
                app.Plotting_Import.YLabel.String = 'Intensity [cps]';
                if strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, '2 Theta')
                    errorbar(app.twotheta_data, app.data(:,2)./app.Time_sEditField.Value, app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                elseif strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, 'Q')
                    app.Plotting_Import.YLabel.String = 'Intensity [cps]';
                    errorbar(app.Q_data, app.data(:,2)./app.Time_sEditField.Value, app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                end
                
            else 
                if strcmp(app.ButtonGroupUnitPlot_Import.SelectedObject.Text, 'cps')
                    app.Plotting_Import.YLabel.String = 'Intensity [cps]';
                elseif strcmp(app.ButtonGroupUnitPlot_Import.SelectedObject.Text, 'counts')
                    app.Plotting_Import.YLabel.String = 'Intensity [counts]';
                end

                if strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, '2 Theta')
                    errorbar(app.twotheta_data, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                elseif strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, 'Q')
                    app.Plotting_Import.YLabel.String = 'Intensity [cps]';
                    errorbar(app.Q_data, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                    app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                    app.Plotting_Import.YScale = 'log';
                    app.Plotting_Import.XLimMode = 'manual';
                    app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
                end
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Selection changed function: ButtonGroupSpacePlot_Import
        function ButtonGroupSpacePlot_ImportSelectionChanged(app, event)
            try
            % change the units of the x-axis in the plot based on selection
            % and re-plot
            if strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, '2 Theta')
                app.Plotting_Import.XLabel.String = '2 Theta [degrees]';
                errorbar(app.twotheta_data, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                app.Plotting_Import.YScale = 'log';
                app.Plotting_Import.XLimMode = 'manual';
                app.x_startEditField_Import.Value = 2*asind(app.x_startEditField_Import.Value/4/pi*app.WavelengthEditField_Import.Value);
                app.x_endEditField_Import.Value = 2*asind(app.x_endEditField_Import.Value/4/pi*app.WavelengthEditField_Import.Value);
                app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
            elseif strcmp(app.ButtonGroupSpacePlot_Import.SelectedObject.Text, 'Q')
                app.Plotting_Import.XLabel.String = 'Q [1/Å]';
                errorbar(app.Q_data, app.data(:,2), app.uY1, 'parent', app.Plotting_Import, 'Color', 'k')
                app.Plotting_Import.Title.String = app.SampleidentifierEditField.Value;
                app.Plotting_Import.YScale = 'log';
                app.Plotting_Import.XLimMode = 'manual';
                app.x_startEditField_Import.Value = 4*pi/app.WavelengthEditField_Import.Value*sind(app.x_startEditField_Import.Value/2);
                app.x_endEditField_Import.Value = 4*pi/app.WavelengthEditField_Import.Value*sind(app.x_endEditField_Import.Value/2);
                app.Plotting_Import.XLim = [app.x_startEditField_Import.Value, app.x_endEditField_Import.Value];
            end

            % change simulate tabe
            app.ThetaButton_Simulate.Value = app.ThetaButton.Value;
            app.QButton_Simulate.Value = app.QButton.Value;

            % change fit tab
            app.ThetaButton_Plot_Fit.Value = app.ThetaButton.Value;
            app.QButton_Plot_Fit.Value = app.QButton.Value;

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: Time_sEditField
        function Time_sEditFieldValueChanged(app, event)
            try
            if app.Time_sEditField.Value <= 0
                app.Time_sEditField.Value = 1;
            end   

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: x_endEditField_Import, 
        % ...and 1 other component
        function x_startEditField_ImportValueChanged(app, event)
            try
            % simulate tab
            app.x_startEditField_Simulate.Value = app.x_startEditField_Import.Value;
            app.x_endEditField_Simulate.Value = app.x_endEditField_Import.Value;

            % fit tab
            app.x_startEditField_Fit.Value = app.x_startEditField_Import.Value;
            app.x_endEditField_Fit.Value = app.x_endEditField_Import.Value;

            % replot all tabs according to new limits
            replot_limits(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: NextButton_Import
        function NextButton_ImportPushed(app, event)
            try
            app.TabGroup.SelectedTab = app.SimulateTab;

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: OnlySimulateSwitch
        function OnlySimulateSwitchValueChanged(app, event)
            try
            if strcmp(app.OnlySimulateSwitch.Value, 'No')
                % clear tabs
                clear_Import(app);
                clear_Simulate(app);
                clear_Fit(app);
                
                % disable going to the next tab
                set(app.NextButton_Import, 'Enable', 'off');

                % make adjustment panel invisible
                set(app.AdjustPanel, 'Visible', 'off');
                set(app.alpha_1EditField, 'Enable', 'off');
                set(app.alpha_2EditField, 'Enable', 'off');
                set(app.sigmaEditField, 'Enable', 'off');
            
            elseif strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                % clear tabs
                clear_Import(app);
                clear_Simulate(app);
                clear_Fit(app);
                app.OnlySimulateSwitch.Value = app.OnlySimulateSwitch.Items{2};
                
                % change current tab
                app.TabGroup.SelectedTab = app.SimulateTab;
                
                % enable going to the next tab
                set(app.NextButton_Import, 'Enable', 'on');
                
                % enable all functions in simulate tab
                prepare_Simulate(app);
                
                % disable import
                disable_Import(app);
                
                % enable wavelength editing
                set(app.WavelengthEditField_Simulate, 'Enable', 'on');
                set(app.x_startEditField_Simulate, 'Enable', 'on');
                set(app.x_endEditField_Simulate, 'Enable', 'on');

                % make adjustment panel visible
                set(app.AdjustPanel, 'Visible', 'on');
                app.alpha_1EditField.Value = 0.029;
                app.alpha_2EditField.Value = 0.004;
                app.sigmaEditField.Value = 4;  
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: StrainedSwitch
        function StrainedSwitchValueChanged(app, event)
            try
            if strcmp(app.StrainedSwitch.Value, 'No')
                set(app.ButtonGroupStrain_Simulate, 'Enable', 'off');
                set(app.CheckBox, 'Enable', 'off');
                app.CheckBox.Value = 0;
                set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'off');
                set(app.CheckBox_2, 'Enable', 'off');
                app.CheckBox_2.Value = 0;

                % if only fitting
                if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                    set(app.alpha_1EditField, 'Enable', 'off');
                    set(app.alpha_2EditField, 'Enable', 'off');
    
                    app.alpha_1EditField.Value = 0.029;
                    app.alpha_2EditField.Value = 0.004;
                end

            elseif strcmp(app.StrainedSwitch.Value, 'Yes')
                set(app.ButtonGroupStrain_Simulate, 'Enable', 'on');
                set(app.CheckBox, 'Enable', 'on');
                app.CheckBox.Value = 1;
                set(app.ButtonGroupStrain_Simulate_cap, 'Enable', 'on');
                set(app.CheckBox_2, 'Enable', 'on');
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: SavesampleButton
        function SavesampleButtonPushed(app, event)
            try
            if check_Simulate(app) == true
                % display the layers
                % 1: Name
                app.layers{1} = string(app.NameEditField_Simulate.Value);
                % 2: Z
                app.layers{2} = str2double(split(string(app.ZEditField.Value), ';'));
                % 3: composition
                app.layers{3} = str2double(split(string(app.compatEditField.Value), ';'));
                % 4: d
                app.layers{4} = app.SpacingEditField.Value;
                % 5: t
                app.layers{5} = app.thicknessEditField.Value;
                % 6: substrate, 7: I, 8: w, 9: x
                if strcmp(app.SubstrateSwitch.Value, 'No')
                    app.layers{6} = 'NoSub';
                    app.layers{7} = 0;
                    app.layers{8} = 0;
                    app.layers{9} = 0;
                elseif strcmp(app.SubstrateSwitch.Value, 'Yes')
                    app.layers{6} = 'Sub';
                    app.layers{7} = app.IcpsEditField.Value;
                    app.layers{8} = app.HWHMdegreesEditField.Value;
                    app.layers{9} = app.dsubEditField.Value;
                end
                % 10: strain
                app.strained = [0,0];
                if strcmp(app.StrainedSwitch.Value, 'No')
                    app.layers{10} = 'NoStrain';
                elseif strcmp(app.StrainedSwitch.Value, 'Yes')
                    app.layers{10} = '';
                    if app.CheckBox.Value == 1
                        if app.tensileButton.Value
                            app.layers{10} = 'sub:t';
                            app.strained(1) = 1;
                        elseif app.compressiveButton.Value
                            app.layers{10} = 'sub:c';
                            app.strained(1) = -1;
                        end
                    end
                    if app.CheckBox_2.Value == 1
                        if app.tensileButton_cap.Value
                            app.layers{10} = strcat(app.layers{10}, 'cap:t');
                            app.strained(2) = 1;
                        elseif app.compressiveButton_cap.Value
                            app.layers{10} = strcat(app.layers{10}, 'cap:c');
                            app.strained(2) = -1;
                        end
                    end
                end
                % 11: rougness
                if strcmp(app.RoughnessSwitch.Value, 'No')
                    app.layers{11} = 'NoRough';
                elseif strcmp(app.RoughnessSwitch.Value, 'Yes')
                    app.layers{11} = 'Rough';
                end
                % 12: Debye-Waller-pre-factor
                app.layers{12} = app.dwEditField_Simulate.Value;
                % 13: density
                app.layers{13} = app.densityEditField.Value;


                % write text in box
                for i = 1:size(app.layers,1)
                    app.sampleStr = string(app.layers{1}) + "      density: " + join(string(app.layers{13})) + "     Z: [" + join(string(app.layers{2})) + "]     dwpf: " + join(string(app.layers{12})) + "      comp: [" + join(string(app.layers{3})) + "]       d: " + join(string(app.layers{4})) + "       t: " + join(string(app.layers{5})) + "        " + join(string(app.layers{6})) + "      I: " + join(string(app.layers{7})) + "       w: " + join(string(app.layers{8})) + "     dsub: " + join(string(app.layers{9})) + "        " + join(string(app.layers{10})) + "        " + join(string(app.layers{11})); 
                end
                app.SampleNameTextArea.Value = app.sampleStr;

                % enable Done button
                set(app.DoneButton, 'Enable', 'on');

                if strcmp(app.SampleNameTextArea.Value, '')
                    app.SampleNameTextArea.Value = "The thin film cannot be simulated because a property has been entered incorrectly. Please check and try again saving a layer.";
                    % disable Done button
                    set(app.DoneButton, 'Enable', 'off');
                
                    % let user know
                    app.SavesampleButton.Text = 'Try saving again.';
                end
            else
                % disable Done button
                set(app.DoneButton, 'Enable', 'off');
                
                % let user know
                app.SavesampleButton.Text = 'Try saving again.';
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: SubstrateSwitch
        function SubstrateSwitchValueChanged(app, event)
            try
            include_substrate(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ResetallButton
        function ResetallButtonPushed(app, event)
            try
            reset_Simulate(app);

            % if only simulating it has to be possible to change the
            % wavelength
            if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                set(app.WavelengthEditField_Simulate, 'Enable', 'on');
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ImportsampleButton
        function ImportsampleButtonPushed(app, event)
            try
            % select path and file
            while true
                [file,path] = uigetfile('*.txt');
                app.thinfilm = [path file];
                if file == 0                            % for "Cancel"
                    app.ImportsampleButton.Text = 'Try import again.';
                    break;
                else                                    % for "Open"
                    app.ImportsampleButton.Text = 'Imported';
                    % process the thin film
                    tf = readcell(app.thinfilm, 'Delimiter',',');
                    
                    % change strings to number arrays
                    tf{2} = str2num(tf{2});
                    tf{3} = str2num(tf{3});
                    
                    % put thin film in
                    app.layers = {};
                    for j = 1:length(tf)
                        app.layers(j) = tf(j);
                    end
                    
                    app.strained = [0,0];
                    if strcmp(app.layers(10), 'sub:t')
                        app.strained = [1,0];
                    elseif strcmp(app.layers(10), 'sub:c')
                        app.strained = [-1,0];
                     elseif strcmp(app.layers(10), 'cap:t')
                        app.strained = [0,1];
                    elseif strcmp(app.layers(10), 'cap:c')
                        app.strained = [0,-1];
                    elseif strcmp(app.layers(10), 'sub:tcap:t')
                        app.strained = [1,1];
                    elseif strcmp(app.layers(10), 'sub:ccap:t')
                        app.strained = [-1,1];
                    elseif strcmp(app.layers(10), 'sub:tcap:c')
                        app.strained = [1,-1];
                    elseif strcmp(app.layers(10), 'sub:ccap:c')
                        app.strained = [-1,-1];
                    end
                    
                    app.CheckBox.Value = 0;
                    app.CheckBox_2.Value = 0;
                    if app.strained(1) ~= 0
                        app.CheckBox.Value = 1;
                    end
                    if app.strained(2) ~= 0
                        app.CheckBox_2.Value = 1;
                    end
                    
                    % read in layer
                    read_in_layer(app);
                    break;
                end
            end
            
            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ExportsampleButton
        function ExportsampleButtonPushed(app, event)
            try
            % select path and file
            while true
                [file,path] = uiputfile('*.txt');
                app.thinfilm = [path file];
                if file == 0                            % for "Cancel"
                    app.ExportsampleButton.Text = 'Try download again.';
                    break;
                else                                    % for "Open"
                    app.ExportsampleButton.Text = 'Downloaded';
                    % test for alloy
                    savetf = app.layers;
                    for j = 1:length(app.layers(1))
                        app.layers{3} = app.layers{3};
                        strarray2 = string(app.layers{2});
                        strarray3 = string(app.layers{3});
                        savetf{2} = strcat('[', join(strarray2), ']');
                        savetf{3} = strcat('[', join(strarray3), ']');
                    end
        
                    % write in file
                    writecell(savetf,app.thinfilm,'Delimiter',',');
                    break;
                end
            end
            
            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            try
            % Done
            if strcmp(app.DoneButton.Text, 'Done')
                % disable Done
                set(app.DoneButton, 'Enable', 'off');
                app.DoneButton.Text = 'Simulating...';
                pause(0.1);
                
                % prepare simulated part of tab
                prepare_Simulated(app);
                
                % simulate
                simulate(app);
                
                % plot it
                if strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, '2 Theta')
                    plot(app.twotheta, app.I, '-r','LineWidth',1.5, 'parent', app.Plotting_Simulate)
                    app.Plotting_Simulate.YScale = 'log';
                    app.Plotting_Simulate.XLimMode = 'manual';
                    app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];
                elseif strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, 'Q')
                    plot(app.Q, app.I, '-r','LineWidth',1.5, 'parent', app.Plotting_Simulate)
                    app.Plotting_Simulate.YScale = 'log';
                    app.Plotting_Simulate.XLimMode = 'manual';
                    app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];
                end
                
                if strcmp(app.OnlySimulateSwitch.Value, 'No')
                    % prepare fit tab
                    prepare_Fit(app);
                elseif strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                    set(app.alpha_1EditField, 'Enable', 'off');
                    set(app.alpha_2EditField, 'Enable', 'off');
                    set(app.sigmaEditField, 'Enable', 'off');
                end
            
            % Edit
            elseif strcmp(app.DoneButton.Text, 'Edit')
                
                % prepare edit simulate part of tab
                edit_Simulate(app);

                % disable fit
                disable_fit(app);

                if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                    if strcmp(app.StrainedSwitch.Value, 'Yes')
                        set(app.alpha_1EditField, 'Enable', 'on');
                        set(app.alpha_2EditField, 'Enable', 'on');
                    end
                    if strcmp(app.RoughnessSwitch.Value, 'Yes')
                        set(app.sigmaEditField, 'Enable', 'on');
                    end
                end
            
            % Re-do
            elseif strcmp(app.DoneButton.Text, 'Re-do')
                % disable Done
                set(app.DoneButton, 'Enable', 'off');
                app.DoneButton.Text = 'Simulating...';
                pause(0.1);

                % prepare simulated part of tab
                prepare_Simulated(app);
                
                % simulate
                simulate(app);
                
                % plot it
                if strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, '2 Theta')
                    plot(app.twotheta, app.I, 'r','LineWidth',1.5, 'parent', app.Plotting_Simulate)
                    app.Plotting_Simulate.YScale = 'log';
                    app.Plotting_Simulate.XLimMode = 'manual';
                    app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];
                elseif strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, 'Q')
                    plot(app.Q, app.I, 'r','LineWidth',1.5, 'parent', app.Plotting_Simulate)
                    app.Plotting_Simulate.YScale = 'log';
                    app.Plotting_Simulate.XLimMode = 'manual';
                    app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];
                end

                if strcmp(app.OnlySimulateSwitch.Value, 'No')
                    if ~strcmp(app.last, 'none')
                        % Do you want to keep your upper and lower boundaries?
                        msg = 'Do you want to keep your upper and lower fitting boundaries?';
                        title = 'Upper and lower boundaries';
                        selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Keep them','Reset them'}, 'DefaultOption',1,'CancelOption',1,'Icon','question');
                        switch selection
                        case 'Keep them'
        
                            % save current boundaries
                            temp_lower = app.lower;
                            temp_higher = app.higher;
                            temp_fitting = app.fitting;

                            % make sure bounds for non-fitting parameters
                            % are all 0

                            % 3: alpha 1, 4: alpha 2
                            if strcmp(app.layers{10}, 'NoStrain')
                                temp_fitting{3} = 0;                
                                temp_lower{3} = 0;
                                temp_higher{3} = 0;
                
                                temp_fitting{4} = 0;
                                temp_lower{4} = 0;
                                temp_higher{4} = 0;
                            else
                                if app.strained(1) == 0
                                    temp_fitting{3} = 0;                
                                    temp_lower{3} = 0;
                                    temp_higher{3} = 0;
                                end
                                if app.strained(2) == 0
                                    temp_fitting{4} = 0;
                                    temp_lower{4} = 0;
                                    temp_higher{4} = 0;
                                end
                            end
                           
                
                            % 5: sigma
                            if strcmp(app.RoughnessSwitch.Value, 'No')
                                temp_fitting{5} = 0;
                                temp_lower{5} = 0;
                                temp_higher{5} = 0;
                            end
                
                            % 6: bkg a, 7: bkg b, 8: bkg c
                            if strcmp(app.BackgroundSwitch.Value, 'linear')
                                temp_fitting{8} = 0;
                                temp_lower{8} = 0;
                                temp_higher{8} = 0;
                            end

                            % 11: I, 12: w, 13: dsub
                            if strcmp(app.layers{6}, 'NoSub')
                                temp_fitting{11} = 0;            
                                temp_lower{11} = 0; 
                                temp_higher{11} = 0; 
                    
                                temp_fitting{12} = 0;          
                                temp_lower{12} = 0; 
                                temp_higher{12} = 0; 
                
                                temp_fitting{13} = 0;            
                                temp_lower{13} = 0;
                                temp_higher{13} = 0;
                            end
        
                            % prepare the fit tab again
                            prepare_Fit(app);
        
                            % set values to former ones
                            app.lower = temp_lower;
                            app.higher = temp_higher;
                            app.fitting = temp_fitting;

                            % display them
                            display_fit(app);
                        case 'Reset them'
        
                            % prepare the fit tab again
                            prepare_Fit(app);
                        end
                    else
                        % prepare the fit tab
                        prepare_Fit(app);
                    end

                    
                end

                if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                    set(app.alpha_1EditField, 'Enable', 'off');
                    set(app.alpha_2EditField, 'Enable', 'off');
                    set(app.sigmaEditField, 'Enable', 'off');
                end
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: NextButton_Simulate
        function NextButton_SimulatePushed(app, event)
            try
            app.TabGroup.SelectedTab = app.FitTab;

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: PreviousButton_Simulate
        function PreviousButton_SimulatePushed(app, event)
            try
            app.TabGroup.SelectedTab = app.ImportTab;

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: PreviousButton_Fit
        function PreviousButton_FitPushed(app, event)
            try
            app.TabGroup.SelectedTab = app.SimulateTab;

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ClearallButton_Fit
        function ClearallButton_FitPushed(app, event)
            try
            msg = 'Clear all will reset all tabs and delete all entered values.';
            title = 'Confirm Clear all';
            selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Clear all','Cancel'}, 'DefaultOption',2,'CancelOption',2,'Icon','warning');

            switch selection
            case 'Clear all'
                % clear fit tab
                clear_Fit(app);
    
                % clear simulate tab
                reset_Simulate(app);
    
                % clear import tab
                clear_Import(app);
                back_to_start(app);

                % return to import tab
                app.TabGroup.SelectedTab = app.ImportTab;
            case 'Cancel'
                return
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ChoosedirectoryButton
        function ChoosedirectoryButtonPushed(app, event)
            try
            % reset gof
            global gofs;
            clear global gofs;
            
            % select path and file            
            while true
               
                folder = uigetdir();
                
    
                if folder == 0                            % for "Cancel"
                    app.ChoosedirectoryButton.Text = 'Try select again.';
                    break;
                else                                      % for "Open"
                    app.ChoosedirectoryButton.Text = 'Folder selected.';
                    cd(folder);
                    % not simulated or fitted yet
                    app.last = 'none';
                    clear_Import(app);
                    if ~isfile('fit_laue_oscillations.m')                 % wrong folder
                        app.ChoosedirectoryButton.Text = 'Wrong folder!';
                        disable_Import(app);
                    else
                        break;
                    end
                end
            end

            % set to front
            % app.GenLversion10UIFigure.WindowStyle = 'alwaysontop';

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: BackgroundSwitch, I_limit_high, 
        % ...and 38 other components
        function dsub_limit_highValueChanged(app, event)
            try
            if strcmp(app.BackgroundSwitch.Value, 'linear')
                % Bkg_c
                set(app.cCheckBox, 'Enable', 'off');
                set(app.bkgc_limit_low, 'Enable', 'off');
                set(app.bkgc_limit_high, 'Enable', 'off');
                set(app.bkgc_startField, 'Enable', 'off');
                % not possible to fit c
                app.fitting{8} = 0;
                app.lower{8} = 0;
                app.higher{8} = 0;
            elseif strcmp(app.BackgroundSwitch.Value, 'polynomial')
                % Bkg_c
                set(app.cCheckBox, 'Enable', 'on');
                set(app.bkgc_limit_low, 'Enable', 'on');
                set(app.bkgc_limit_high, 'Enable', 'on');
                set(app.bkgc_startField, 'Enable', 'on');
            end

            % set back fitting and simulation
            app.StartfittingButton.Text = 'Start fitting';
            app.SimulateButton.Text = 'Simulate';

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: StartfittingButton
        function StartfittingButtonPushed(app, event)
            try
            % update values for fitting incl. upper and lower bounds
            update_flh(app);

            % check whether simulation would work
            works_size = check_size(app);
            works_para = check_fit(app);
            
            % array size ok
            if works_size == 1 && works_para == 1

                % change tab
                app.DisplayDropDown.Value = 'Fitting';
                app.last = 'none';
                DisplayDropDownValueChanged(app, event)

                % last thing we did
                app.last = 'fit';

                % enable progress
                set(app.Gauge, 'Enable', 'on');
                set(app.RefreshProgressButton, 'Enable', 'on');
                app.Gauge.Value = 0;
    
                % change start fitting button
                app.StartfittingButton.Text = 'Fit is running.';
                set(app.StartfittingButton, 'Enable', 'off');

                % construct S_struct
                create_S_struct(app);
                
                % disable editing
                disable_fit(app);
    
                % select plot tab "fitting"
                app.DisplayDropDown.Value = 'Fitting';
                set(app.DisplayDropDown, 'Enable', 'off');

                % set progress to 100
                global progress;
                progress = 0;
                app.Gauge.Value = progress;

                pause(2);

                % enable stop fitting button
                set(app.StopfittingButton, 'Enable', 'on');
                app.StopfittingButton.Text = 'Stop fitting.';

                pause(1);
    
                % fit
                fit_laue_oscillations(app);
    
                % display fitted values
                if strcmp(app.StopfittingButton.Text, 'Stop fitting.') 
                    display_after_fit_values(app);
                end

                % update simulation
                simulate_fit(app);

                % plot it
                plot_in_fit_tab(app);

                % set progress to 100
                app.Gauge.Value = progress;

                % disable progress
                set(app.Gauge, 'Enable', 'off');
                set(app.RefreshProgressButton, 'Enable', 'off');
    
                % enable editing
                enable_fit(app);
    
                % enable plot selection
                set(app.DisplayDropDown, 'Enable', 'on');
    
                % change start fitting button
                set(app.StartfittingButton, 'Enable', 'on');
    
                % disable stop fitting button
                set(app.StopfittingButton, 'Enable', 'off');

                % enable buttons
                set(app.ExportfitButton, 'Enable', 'on');
                set(app.ExportparameterButton, 'Enable', 'on');

                % set back simulate
                app.SimulateButton.Text = 'Simulate';
            else
                msg = 'You have to select at least 1 parameter to be fitted and the start value has to lie in between lower and upper bounds.';
                title = 'Fitting does not work like this!';
                selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Got it!'}, 'DefaultOption',1,'CancelOption',1,'Icon','warning');
                switch selection
                case 'Got it!'
                    return
                end
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: StopfittingButton
        function StopfittingButtonPushed(app, event)
            try
            % stop fitting
            global flag_stopped;
            flag_stopped = 1;
            
            % change start fitting button
            app.StartfittingButton.Text = 'Re-start fitting.';
            set(app.StartfittingButton, 'Enable', 'on');

            % disable stop fitting button
            set(app.StopfittingButton, 'Enable', 'off');
            app.StopfittingButton.Text = 'Fitting stopped.';

            % ask whether to display fitted values
            msg = 'Do you want to keep your fitted values?';
            title = 'Fitting was stopped.';
            selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Keep fitted values','Back to before'}, 'DefaultOption',1,'CancelOption',2,'Icon','question');
            switch selection
            case 'Keep fitted values'
               % display fitted values
               display_after_fit_values(app);

            case 'Back to before'
                return
            end

            % disable progress
            set(app.Gauge, 'Enable', 'off');

            % change text
            app.SimulateButton.Text = 'Simulate';

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Selection changed function: ButtonGroupSpacePlot_Fit
        function ButtonGroupSpacePlot_FitSelectionChanged(app, event)
            try
            % change the units of the x-axis in the plot based on selection
            if strcmp(app.ButtonGroupSpacePlot_Fit.SelectedObject.Text, '2 Theta')
                app.Plotting_Fit.XLabel.String = '2 Theta [degrees]';
                app.Plotting_Fit.YScale = 'log';
                app.Plotting_Fit.XLimMode = 'manual';
                app.x_startEditField_Fit.Value = 2*asind(app.x_startEditField_Fit.Value/4/pi*app.WavelengthEditField_Simulate.Value);
                app.x_endEditField_Fit.Value = 2*asind(app.x_endEditField_Fit.Value/4/pi*app.WavelengthEditField_Simulate.Value);
                app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];

                % change simulate tab
                app.ThetaButton_Simulate.Value = 1;
                app.QButton_Simulate.Value = 0;
            elseif strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, 'Q')
                app.Plotting_Fit.XLabel.String = 'Q [1/Å]';
                app.Plotting_Fit.YScale = 'log';
                app.Plotting_Fit.XLimMode = 'manual';
                app.x_startEditField_Fit.Value = 4*pi/app.WavelengthEditField_Simulate.Value*sind(app.x_startEditField_Fit.Value/2);
                app.x_endEditField_Fit.Value = 4*pi/app.WavelengthEditField_Simulate.Value*sind(app.x_endEditField_Fit.Value/2);
                app.Plotting_Fit.XLim = [app.x_startEditField_Fit.Value, app.x_endEditField_Fit.Value];

                % change simulate tab
                app.ThetaButton_Simulate.Value = 0;
                app.QButton_Simulate.Value = 1;
            end

            % replot it
            plot_in_fit_tab(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Selection changed function: ButtonGroupSpacePlot_Simulate
        function ButtonGroupSpacePlot_SimulateSelectionChanged(app, event)
            try
            % change the units of the x-axis in the plot based on selection
            % and re-plot
            if strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, '2 Theta')
                app.Plotting_Simulate.XLabel.String = '2 Theta [degrees]';
                plot(app.twotheta, app.I, '-r','LineWidth',1.5, 'parent', app.Plotting_Simulate, 'Color', 'r')
                app.Plotting_Simulate.YScale = 'log';
                app.Plotting_Simulate.XLimMode = 'manual';
                app.x_startEditField_Simulate.Value = 2*asind(app.x_startEditField_Simulate.Value/4/pi*app.WavelengthEditField_Simulate.Value);
                app.x_endEditField_Simulate.Value = 2*asind(app.x_endEditField_Simulate.Value/4/pi*app.WavelengthEditField_Simulate.Value);
                app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];

                % change fit tab
                app.ThetaButton_Plot_Fit.Value = 1;
                app.QButton_Plot_Fit.Value = 0;
            elseif strcmp(app.ButtonGroupSpacePlot_Simulate.SelectedObject.Text, 'Q')
                app.Plotting_Simulate.XLabel.String = 'Q [1/Å]';
                plot(app.Q, app.I, '-r','LineWidth',1.5, 'parent', app.Plotting_Simulate, 'Color', 'r')
                app.Plotting_Simulate.YScale = 'log';
                app.Plotting_Simulate.XLimMode = 'manual';
                app.x_startEditField_Simulate.Value = 4*pi/app.WavelengthEditField_Simulate.Value*sind(app.x_startEditField_Simulate.Value/2);
                app.x_endEditField_Simulate.Value = 4*pi/app.WavelengthEditField_Simulate.Value*sind(app.x_endEditField_Simulate.Value/2);
                app.Plotting_Simulate.XLim = [app.x_startEditField_Simulate.Value, app.x_endEditField_Simulate.Value];

                % change fit tab
                app.ThetaButton_Plot_Fit.Value = 0;
                app.QButton_Plot_Fit.Value = 1;
            end    

            ButtonGroupSpacePlot_FitSelectionChanged(app,event);
            
            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
            % check whether simulation would work
            works = check_size(app);
            
            % array size ok
            if works == 1
                % last thing we did
                app.last = 'sim';

                % disable simulate
                set(app.SimulateButton, 'Enable', 'off');

                % change text
                app.SimulateButton.Text = 'Simulating...';
    
                % disable editing
                disable_fit(app);
                set(app.StartfittingButton, 'Enable', 'off');
                pause(1);
    
                % change tab
                app.DisplayDropDown.Value = 'Fitting';
                
                % simulate based on properties in fit tab
                simulate_fit(app);
    
                % change text
                app.SimulateButton.Text = 'Simulate';
    
                % plot it
                plot_in_fit_tab(app);
    
                % enable editing
                enable_fit(app);
                app.StartfittingButton.Text = 'Start Fitting';
                set(app.StartfittingButton, 'Enable', 'on');
                set(app.ExportfitButton, 'Enable', 'on');
                set(app.ExportparameterButton, 'Enable', 'on');
                set(app.ButtonGroupSpacePlot_Fit, 'Enable', 'on');
                set(app.ButtonGroupUnitPlot_Fit, 'Enable', 'on');
                set(app.x_startEditField_Fit, 'Enable', 'on');
                set(app.x_endEditField_Fit, 'Enable', 'on');

                % enable simulate
                set(app.SimulateButton, 'Enable', 'on');
            
            % array size too large
            elseif works == 0
                app.SimulateButton.Text = 'Aborted.';
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            try
            % replot
            plot_in_fit_tab(app);

            % disable plot settings for other tabs
            if strcmp(app.DisplayDropDown.Value, 'Fitting')
                set(app.ButtonGroupSpacePlot_Fit, 'Enable', 'on');
                set(app.ButtonGroupUnitPlot_Fit, 'Enable', 'on');
                set(app.x_endEditField_Fit, 'Enable', 'on');
                set(app.x_startEditField_Fit, 'Enable', 'on');
            else
                set(app.ButtonGroupSpacePlot_Fit, 'Enable', 'off');
                set(app.ButtonGroupUnitPlot_Fit, 'Enable', 'off');
                set(app.x_endEditField_Fit, 'Enable', 'off');
                set(app.x_startEditField_Fit, 'Enable', 'off');
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Selection changed function: ButtonGroupUnitPlot_Fit
        function ButtonGroupUnitPlot_FitSelectionChanged(app, event)
            try
            % replot it
            plot_in_fit_tab(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ExportparameterButton
        function ExportparameterButtonPushed(app, event)
            try
            % download
            download_parameters(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: x_endEditField_Fit, x_startEditField_Fit
        function x_startEditField_FitValueChanged(app, event)
            try
            % simulate tab
            app.x_endEditField_Simulate.Value = app.x_endEditField_Fit.Value;
            app.x_startEditField_Simulate.Value = app.x_startEditField_Fit.Value;

            % import tab
            app.x_endEditField_Import.Value = app.x_endEditField_Fit.Value;
            app.x_startEditField_Import.Value = app.x_startEditField_Fit.Value;

            % replot all tabs according to new limits
            replot_limits(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: x_endEditField_Simulate, 
        % ...and 1 other component
        function x_startEditField_SimulateValueChanged(app, event)
            try
            % fit tab
            app.x_endEditField_Fit.Value = app.x_endEditField_Simulate.Value;
            app.x_startEditField_Fit.Value = app.x_startEditField_Simulate.Value;

            % import tab
            app.x_endEditField_Import.Value = app.x_endEditField_Simulate.Value;
            app.x_startEditField_Import.Value = app.x_startEditField_Simulate.Value;

            % replot all tabs according to new limits
            replot_limits(app);

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ExportfitButton
        function ExportfitButtonPushed(app, event)
            try
            % if strain is present
            if ~strcmp(app.layers{10}, 'NoStrain')
                msg = 'Do you want to export the strain profile as well?';
                title = 'Export fit and strain profile?';
                selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Only fit','Both', 'Cancel'}, 'DefaultOption',2,'CancelOption',3,'Icon','question');
                switch selection
                case 'Only fit'
                    % export fit
                    download_fit(app);
                case 'Both'
                    % export fit
                    download_fit(app);

                    % export strain profile
                    download_strain_profile(app);
                case 'Cancel'
                    return
                end

           % if no strain is present
            else 
                %  export fit
                download_fit(app);
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: RefreshProgressButton
        function RefreshProgressButtonPushed(app, event)
            try
            global progress;

            app.Gauge.Value = progress;

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            try
            Z = str2double(split(string(app.ZEditField.Value), ';'));

            % is it a pure element?
            if length(Z) == 1
                % check if Debye-Waller factor is in database
                app.dwEditField_Simulate.Value = DB_prefactor(Z);
            else
                app.dwEditField_Simulate.Value = 2;
            end

            % is it an alloy or not in the database
            if app.dwEditField_Simulate.Value == 0
                msg = 'Please add a Debye-Waller pre-factor manually!';
                title = 'We do not have a Debye-Waller pre-factor in the database for this layer.';
                selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Yes, I will do that.'}, 'DefaultOption',1,'CancelOption',1,'Icon','warning');
                switch selection
                case 'Yes, I will do that.'
                    return
                end
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: RoughnessSwitch
        function RoughnessSwitchValueChanged(app, event)
            try
            if strcmp(app.RoughnessSwitch.Value, 'Yes')
            
                % if only fitting
                if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                    set(app.sigmaEditField, 'Enable', 'on');
    
                    app.sigmaEditField.Value = 4;
                end

            elseif strcmp(app.RoughnessSwitch.Value, 'No')

                % if only fitting
                if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                    set(app.sigmaEditField, 'Enable', 'off');
    
                    app.sigmaEditField.Value = 4;
                end

            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Button pushed function: ExportgraphButton
        function ExportgraphButtonPushed(app, event)
            try
            if length(app.I) >= 1
                % select path and file
                while true
                    [file,path] = uiputfile('*.txt');
                    simdownload = [path file];
                    if file == 0                            % for "Cancel"
                        app.ExportgraphButton.Text = 'Try again.';
                        break;
                    else                                    % for "Open"
                        app.ExportfitButton.Text = 'Export graph'; 
                           
                        % put data in array
                        dataarray = zeros(length(app.Q), 3);
                        dataarray(:,1) = app.twotheta;
                        dataarray(:,2) = app.Q;
                        dataarray(:,3) = app.I;
                        
                        % add heading
                        fid = fopen(simdownload, 'w');
                        fprintf(fid, '2Theta [deg], Q [Å^-1], Sim: I\n');
                        for i = 1:length(dataarray(:,1))
                            fprintf(fid, '%f   %f    %f\n', dataarray(i,1), dataarray(i,2), dataarray(i,3));
                        end
                        fclose(fid);
                        break;
                    end
                end
            else
                msg = 'You need to simulate first.';
                title = 'No simulation found.';
                selection = uiconfirm(app.GenLversion10UIFigure,msg,title,'Options',{'Got it!'}, 'DefaultOption',1,'CancelOption',1,'Icon','warning');
                switch selection
                case 'Got it!'
                    return
                end
            end

            catch ME
                report = getReport(ME);
                uialert(app.GenLversion10UIFigure,report,"Error Message","Interpreter","html");
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            % if only fitting
            if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                set(app.alpha_1EditField, 'Enable', 'on');

                app.alpha_1EditField.Value = 0.029;
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            % if only fitting
            if strcmp(app.OnlySimulateSwitch.Value, 'Yes')
                set(app.alpha_2EditField, 'Enable', 'on');

                app.alpha_2EditField.Value = 0.004;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GenLversion10UIFigure = uifigure('Visible', 'off');
            app.GenLversion10UIFigure.Name = 'GenL (version 1.0)';
            app.GenLversion10UIFigure.Icon = fullfile(pathToMLAPP, 'GenL_Logo_Big.png');
            app.TabGroup = uitabgroup(app.GenLversion10UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.Plotting_Import = uiaxes(app.ImportTab);
            xlabel(app.Plotting_Import, '2 Theta [degrees]')
            ylabel(app.Plotting_Import, 'Intensity [counts]')
            zlabel(app.Plotting_Import, 'Z')
            app.Plotting_Import.XLim = [60 70];
            app.PlotsettingsLabel = uilabel(app.ImportTab);
            app.PlotsettingsLabel.Text = ' Plot settings ';
            app.SampleinformationLabel = uilabel(app.ImportTab);
            app.SampleinformationLabel.Text = ' Sample information ';
            app.ImportButton = uibutton(app.ImportTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Enable = 'off';
            app.ImportButton.Tooltip = {''};
            app.ImportButton.Text = 'Import';
            app.SampleidentifierEditFieldLabel = uilabel(app.ImportTab);
            app.SampleidentifierEditFieldLabel.Text = 'Sample identifier';
            app.SampleidentifierEditField = uieditfield(app.ImportTab, 'text');
            app.SampleidentifierEditField.Enable = 'off';
            app.SampleidentifierEditField.Tooltip = {'name/identifier of your sample, e.g. 20230916-Fe'};
            app.SampleidentifierEditField.Value = 'Sample';
            app.ButtonGroupUnit_Import = uibuttongroup(app.ImportTab);
            app.ButtonGroupUnit_Import.Tooltip = {'unit of the intensity in the data file'};
            app.ButtonGroupUnit_Import.Enable = 'off';
            app.countsButton = uiradiobutton(app.ButtonGroupUnit_Import);
            app.countsButton.Text = 'counts';
            app.countsButton.Value = true;
            app.cpsButton = uiradiobutton(app.ButtonGroupUnit_Import);
            app.cpsButton.Text = 'cps';
            app.UnitLabel = uilabel(app.ImportTab);
            app.UnitLabel.Text = 'Unit';
            app.TimepointsLabel = uilabel(app.ImportTab);
            app.TimepointsLabel.Text = 'Time/point [s]';
            app.Time_sEditField = uieditfield(app.ImportTab, 'numeric');
            app.Time_sEditField.ValueChangedFcn = createCallbackFcn(app, @Time_sEditFieldValueChanged, true);
            app.Time_sEditField.Enable = 'off';
            app.Time_sEditField.Tooltip = {'measurement time per point'};
            app.Time_sEditField.Value = 1;
            app.WavelengthLabel = uilabel(app.ImportTab);
            app.WavelengthLabel.Text = 'Wavelength [Å]';
            app.WavelengthEditField_Import = uieditfield(app.ImportTab, 'numeric');
            app.WavelengthEditField_Import.ValueDisplayFormat = '%11.5g';
            app.WavelengthEditField_Import.Enable = 'off';
            app.WavelengthEditField_Import.Tooltip = {'wavelength of used diffractometer'};
            app.WavelengthEditField_Import.Value = 1.5418;
            app.NextButton_Import = uibutton(app.ImportTab, 'push');
            app.NextButton_Import.ButtonPushedFcn = createCallbackFcn(app, @NextButton_ImportPushed, true);
            app.NextButton_Import.Enable = 'off';
            app.NextButton_Import.Text = 'Next';
            app.ButtonGroupSpace_Import = uibuttongroup(app.ImportTab);
            app.ButtonGroupSpace_Import.Tooltip = {'select if data in file is given in 2Theta or in Q'};
            app.ButtonGroupSpace_Import.Enable = 'off';
            app.ThetaButton = uiradiobutton(app.ButtonGroupSpace_Import);
            app.ThetaButton.Text = '2 Theta';
            app.ThetaButton.Value = true;
            app.QButton = uiradiobutton(app.ButtonGroupSpace_Import);
            app.QButton.Text = 'Q';
            app.DataLabel = uilabel(app.ImportTab);
            app.DataLabel.Text = 'Data';
            app.DeleteButton = uibutton(app.ImportTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.StartLabel = uilabel(app.ImportTab);
            app.StartLabel.Text = 'Start';
            app.DiffractiondataLabel = uilabel(app.ImportTab);
            app.DiffractiondataLabel.Text = 'Diffraction data';
            app.x_startEditField_Import = uieditfield(app.ImportTab, 'numeric');
            app.x_startEditField_Import.ValueChangedFcn = createCallbackFcn(app, @x_startEditField_ImportValueChanged, true);
            app.x_startEditField_Import.Enable = 'off';
            app.x_startEditField_Import.Tooltip = {'start value of x-axis'};
            app.x_startEditField_Import.Value = 60;
            app.XaxisLabel = uilabel(app.ImportTab);
            app.XaxisLabel.Text = 'X-axis:';
            app.EndLabel = uilabel(app.ImportTab);
            app.EndLabel.Text = 'End';
            app.x_endEditField_Import = uieditfield(app.ImportTab, 'numeric');
            app.x_endEditField_Import.ValueChangedFcn = createCallbackFcn(app, @x_startEditField_ImportValueChanged, true);
            app.x_endEditField_Import.Enable = 'off';
            app.x_endEditField_Import.Tooltip = {'end value of x-axis'};
            app.x_endEditField_Import.Value = 70;
            app.LoaddataButton = uibutton(app.ImportTab, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Enable = 'off';
            app.LoaddataButton.Tooltip = {'Load the diffraction data you would like to fit.'};
            app.LoaddataButton.Text = 'Load data';
            app.PlotLabel = uilabel(app.ImportTab);
            app.PlotLabel.Text = 'Plot';
            app.ButtonGroupSpacePlot_Import = uibuttongroup(app.ImportTab);
            app.ButtonGroupSpacePlot_Import.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSpacePlot_ImportSelectionChanged, true);
            app.ButtonGroupSpacePlot_Import.Enable = 'off';
            app.ThetaButton_Plot = uiradiobutton(app.ButtonGroupSpacePlot_Import);
            app.ThetaButton_Plot.Text = '2 Theta';
            app.ThetaButton_Plot.Value = true;
            app.QButton_Plot = uiradiobutton(app.ButtonGroupSpacePlot_Import);
            app.QButton_Plot.Text = 'Q';
            app.ButtonGroupUnitPlot_Import = uibuttongroup(app.ImportTab);
            app.ButtonGroupUnitPlot_Import.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupUnitPlot_ImportSelectionChanged, true);
            app.ButtonGroupUnitPlot_Import.Enable = 'off';
            app.countsButton_Plot = uiradiobutton(app.ButtonGroupUnitPlot_Import);
            app.countsButton_Plot.Text = 'counts';
            app.countsButton_Plot.Value = true;
            app.cpsButton_Plot = uiradiobutton(app.ButtonGroupUnitPlot_Import);
            app.cpsButton_Plot.Text = 'cps';
            app.ExportdataButton = uibutton(app.ImportTab, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Enable = 'off';
            app.ExportdataButton.Text = 'Export data';
            app.MatlabfolderinformationLabel = uilabel(app.ImportTab);
            app.MatlabfolderinformationLabel.Text = ' Matlab folder information ';
            app.ChoosedirectoryButton = uibutton(app.ImportTab, 'push');
            app.ChoosedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosedirectoryButtonPushed, true);
            app.ChoosedirectoryButton.Text = 'Choose directory';
            app.PleasefirstselectthefolderwithallMatlabfilesforthisprogramLabel = uilabel(app.ImportTab);
            app.PleasefirstselectthefolderwithallMatlabfilesforthisprogramLabel.Text = 'Please first select the folder with all Matlab files for this program.';
            app.Fileshouldconsistof2columnsxandIntensityLabel = uilabel(app.ImportTab);
            app.Fileshouldconsistof2columnsxandIntensityLabel.Text = '*File should consist of 2 columns: x and Intensity.';
            app.ModeLabel = uilabel(app.ImportTab);
            app.ModeLabel.Text = ' Mode';
            app.OnlysimulatewithoutfittingSwitchLabel = uilabel(app.ImportTab);
            app.OnlysimulatewithoutfittingSwitchLabel.Text = 'Only simulate without fitting?';
            app.OnlySimulateSwitch = uiswitch(app.ImportTab, 'slider');
            app.OnlySimulateSwitch.Items = {'No', 'Yes'};
            app.OnlySimulateSwitch.ValueChangedFcn = createCallbackFcn(app, @OnlySimulateSwitchValueChanged, true);
            app.OnlySimulateSwitch.Enable = 'off';
            app.OnlySimulateSwitch.Tooltip = {'Select if you would like to import data and fit the data ("No") or if you would only like to simulate a scattering pattern theoretically ("Yes").'};
            app.OnlySimulateSwitch.Value = 'No';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.Plotting_Simulate = uiaxes(app.SimulateTab);
            xlabel(app.Plotting_Simulate, '2 Theta [degrees]')
            ylabel(app.Plotting_Simulate, 'Intensity')
            zlabel(app.Plotting_Simulate, 'Z')
            app.Plotting_Simulate.XLim = [60 70];
            app.Panel = uipanel(app.SimulateTab);
            app.PolarizationcorrectionLabel = uilabel(app.Panel);
            app.PolarizationcorrectionLabel.Text = 'Polarization correction';
            app.SourceLabel = uilabel(app.Panel);
            app.SourceLabel.Text = 'Source';
            app.WavelengthEditField_2Label_5 = uilabel(app.Panel);
            app.WavelengthEditField_2Label_5.Text = 'Wavelength [Å]';
            app.WavelengthEditField_Simulate = uieditfield(app.Panel, 'numeric');
            app.WavelengthEditField_Simulate.Limits = [0 Inf];
            app.WavelengthEditField_Simulate.ValueDisplayFormat = '%11.5g';
            app.WavelengthEditField_Simulate.Enable = 'off';
            app.WavelengthEditField_Simulate.Tooltip = {'wavelength of used diffractometer'};
            app.WavelengthEditField_Simulate.Value = 1.5418;
            app.WavelengthEditField_2Label_6 = uilabel(app.Panel);
            app.WavelengthEditField_2Label_6.Text = 'Theta_m [degrees]';
            app.theta_m = uieditfield(app.Panel, 'numeric');
            app.theta_m.Limits = [0 Inf];
            app.theta_m.ValueDisplayFormat = '%11.5g';
            app.theta_m.Enable = 'off';
            app.theta_m.Tooltip = {'polarisation factor, e.g.:'; '0: unpolarized'; '2: Incident angle of a Göbel mirror'};
            app.theta_m.Value = 2;
            app.MonochromatoronsideofLabel = uilabel(app.Panel);
            app.MonochromatoronsideofLabel.Text = 'Monochromator on side of:';
            app.MonochromatoronsideofSwitch = uiswitch(app.Panel, 'slider');
            app.MonochromatoronsideofSwitch.Items = {'source', 'detector'};
            app.MonochromatoronsideofSwitch.Tooltip = {'If you use a monochromator, please put in if it is on the side of the source or the detector.'};
            app.MonochromatoronsideofSwitch.Value = 'source';
            app.InstrumentLabel = uilabel(app.SimulateTab);
            app.InstrumentLabel.Text = ' Instrument';
            app.ButtonGroup_2 = uibuttongroup(app.SimulateTab);
            app.dLabel_2 = uilabel(app.ButtonGroup_2);
            app.dLabel_2.Text = 'd(sub) [Å]';
            app.FWHMdegreesLabel = uilabel(app.ButtonGroup_2);
            app.FWHMdegreesLabel.Text = 'HWHM  [degrees]';
            app.IcpsEditField_2Label = uilabel(app.ButtonGroup_2);
            app.IcpsEditField_2Label.Text = 'I [cps]';
            app.IcpsEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.IcpsEditField.Limits = [0 Inf];
            app.IcpsEditField.Enable = 'off';
            app.IcpsEditField.Tooltip = {'intensity of the substrate peak'};
            app.IcpsEditField.Value = 1;
            app.HWHMdegreesEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.HWHMdegreesEditField.Limits = [0 Inf];
            app.HWHMdegreesEditField.Enable = 'off';
            app.HWHMdegreesEditField.Tooltip = {'width of the substrate peak'};
            app.HWHMdegreesEditField.Value = 0.001;
            app.dsubEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.dsubEditField.Limits = [0 Inf];
            app.dsubEditField.Enable = 'off';
            app.dsubEditField.Tooltip = {'interplanar spacing of substrate diffraction planes'};
            app.dsubEditField.Value = 1;
            app.densityEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.densityEditField.Limits = [0 Inf];
            app.densityEditField.ValueDisplayFormat = '%11.5g';
            app.densityEditField.Enable = 'off';
            app.densityEditField.Tooltip = {'density in formula units per unit cell volume for the calculation of the absorption coefficient'};
            app.WavelengthEditField_2Label_4 = uilabel(app.ButtonGroup_2);
            app.WavelengthEditField_2Label_4.Text = 'density [f.u./Å³]';
            app.SampleNameTextAreaLabel_3 = uilabel(app.ButtonGroup_2);
            app.SampleNameTextAreaLabel_3.Text = 'Substrate';
            app.SubstrateSwitch = uiswitch(app.ButtonGroup_2, 'slider');
            app.SubstrateSwitch.Items = {'No', 'Yes'};
            app.SubstrateSwitch.ValueChangedFcn = createCallbackFcn(app, @SubstrateSwitchValueChanged, true);
            app.SubstrateSwitch.Enable = 'off';
            app.SubstrateSwitch.Tooltip = {'Do you want to include an empirical peak from a substrate?'};
            app.SubstrateSwitch.Value = 'No';
            app.SampleNameTextArea = uitextarea(app.ButtonGroup_2);
            app.SampleNameTextArea.Editable = 'off';
            app.SampleNameTextArea.Enable = 'off';
            app.SampleNameTextArea.Value = {'Your simulation will be displayed here once you save the sample.'};
            app.SampleinformationLabel_2 = uilabel(app.ButtonGroup_2);
            app.SampleinformationLabel_2.Text = ' Sample information ';
            app.DoneButton = uibutton(app.ButtonGroup_2, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.SampleNameTextAreaLabel_4 = uilabel(app.ButtonGroup_2);
            app.SampleNameTextAreaLabel_4.Text = 'Strain';
            app.NameEditField_Simulate = uieditfield(app.ButtonGroup_2, 'text');
            app.NameEditField_Simulate.Enable = 'off';
            app.NameEditField_Simulate.Tooltip = {'name/identifier of your simulated sample, e.g. iron'};
            app.NameEditField_Simulate.Value = 'Iron';
            app.ButtonGroupStrain_Simulate_cap = uibuttongroup(app.ButtonGroup_2);
            app.ButtonGroupStrain_Simulate_cap.Tooltip = {'Is it tensile or compressive out-of-plane strain?'};
            app.ButtonGroupStrain_Simulate_cap.Enable = 'off';
            app.ButtonGroupStrain_Simulate_cap.Title = 'From the cap:';
            app.tensileButton_cap = uiradiobutton(app.ButtonGroupStrain_Simulate_cap);
            app.tensileButton_cap.Text = 'tensile';
            app.tensileButton_cap.Value = true;
            app.compressiveButton_cap = uiradiobutton(app.ButtonGroupStrain_Simulate_cap);
            app.compressiveButton_cap.Text = 'compressive';
            app.CheckBox_2 = uicheckbox(app.ButtonGroupStrain_Simulate_cap);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.ButtonGroup = uibuttongroup(app.SimulateTab);
            app.ButtonGroup.Tooltip = {''};
            app.ButtonGroupSpacePlot_Simulate = uibuttongroup(app.ButtonGroup);
            app.ButtonGroupSpacePlot_Simulate.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSpacePlot_SimulateSelectionChanged, true);
            app.ButtonGroupSpacePlot_Simulate.Enable = 'off';
            app.ThetaButton_Simulate = uiradiobutton(app.ButtonGroupSpacePlot_Simulate);
            app.ThetaButton_Simulate.Text = '2 Theta';
            app.ThetaButton_Simulate.Value = true;
            app.QButton_Simulate = uiradiobutton(app.ButtonGroupSpacePlot_Simulate);
            app.QButton_Simulate.Text = 'Q';
            app.PlotLabel_3 = uilabel(app.ButtonGroup);
            app.PlotLabel_3.Text = 'Plot';
            app.XaxisLabel_Simulate = uilabel(app.ButtonGroup);
            app.XaxisLabel_Simulate.Text = 'X-axis:';
            app.StartLabel_2 = uilabel(app.ButtonGroup);
            app.StartLabel_2.Text = 'Start';
            app.x_startEditField_Simulate = uieditfield(app.ButtonGroup, 'numeric');
            app.x_startEditField_Simulate.ValueChangedFcn = createCallbackFcn(app, @x_startEditField_SimulateValueChanged, true);
            app.x_startEditField_Simulate.Enable = 'off';
            app.x_startEditField_Simulate.Tooltip = {'start value of x-axis'};
            app.x_startEditField_Simulate.Value = 60;
            app.EndLabel_2 = uilabel(app.ButtonGroup);
            app.EndLabel_2.Text = 'End';
            app.x_endEditField_Simulate = uieditfield(app.ButtonGroup, 'numeric');
            app.x_endEditField_Simulate.ValueChangedFcn = createCallbackFcn(app, @x_startEditField_SimulateValueChanged, true);
            app.x_endEditField_Simulate.Enable = 'off';
            app.x_endEditField_Simulate.Tooltip = {'end value of x-axis'};
            app.x_endEditField_Simulate.Value = 70;
            app.PlotsettingsLabel_2 = uilabel(app.SimulateTab);
            app.PlotsettingsLabel_2.Text = ' Plot settings ';
            app.NextButton_Simulate = uibutton(app.SimulateTab, 'push');
            app.NextButton_Simulate.ButtonPushedFcn = createCallbackFcn(app, @NextButton_SimulatePushed, true);
            app.NextButton_Simulate.Enable = 'off';
            app.NextButton_Simulate.Text = 'Next';
            app.PreviousButton_Simulate = uibutton(app.SimulateTab, 'push');
            app.PreviousButton_Simulate.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_SimulatePushed, true);
            app.PreviousButton_Simulate.Text = 'Previous';
            app.SimulateLabel = uilabel(app.SimulateTab);
            app.SimulateLabel.Text = 'Simulate';
            app.ZEditField = uieditfield(app.SimulateTab, 'text');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Enable = 'off';
            app.ZEditField.Tooltip = {'atomic numbers of single element, e.g. 26 or of alloy, e.g. 26;27'};
            app.ZEditField.Value = 'Z1; Z2; ...';
            app.dwEditField_Simulate = uieditfield(app.SimulateTab, 'numeric');
            app.dwEditField_Simulate.ValueDisplayFormat = '%11.5g';
            app.dwEditField_Simulate.Enable = 'off';
            app.dwEditField_Simulate.Tooltip = {'element specific Debye-Waller pre-factor'};
            app.compatEditField = uieditfield(app.SimulateTab, 'text');
            app.compatEditField.Enable = 'off';
            app.compatEditField.Tooltip = {'composition of single element, e.g. 100 or of alloy, e.g. 80;20'};
            app.compatEditField.Value = 'x1; x2; ...';
            app.SpacingEditField = uieditfield(app.SimulateTab, 'numeric');
            app.SpacingEditField.Limits = [0 Inf];
            app.SpacingEditField.Enable = 'off';
            app.SpacingEditField.Tooltip = {'interplanar spacing of diffraction planes'};
            app.SpacingEditField.Value = 1;
            app.tLabel = uilabel(app.SimulateTab);
            app.tLabel.Text = 't [Å]';
            app.thicknessEditField = uieditfield(app.SimulateTab, 'numeric');
            app.thicknessEditField.Limits = [0 Inf];
            app.thicknessEditField.Enable = 'off';
            app.thicknessEditField.Tooltip = {'thin film thickness'};
            app.thicknessEditField.Value = 1;
            app.dLabel = uilabel(app.SimulateTab);
            app.dLabel.Text = 'd [Å]';
            app.compatEditFieldLabel = uilabel(app.SimulateTab);
            app.compatEditFieldLabel.Text = 'comp. [at.%]';
            app.ZEditFieldLabel = uilabel(app.SimulateTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.SavesampleButton = uibutton(app.SimulateTab, 'push');
            app.SavesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavesampleButtonPushed, true);
            app.SavesampleButton.Enable = 'off';
            app.SavesampleButton.Text = 'Save sample';
            app.ButtonGroupStrain_Simulate = uibuttongroup(app.SimulateTab);
            app.ButtonGroupStrain_Simulate.Tooltip = {'Is it tensile or compressive out-of-plane strain?'};
            app.ButtonGroupStrain_Simulate.Enable = 'off';
            app.ButtonGroupStrain_Simulate.Title = '  From the substrate:';
            app.tensileButton = uiradiobutton(app.ButtonGroupStrain_Simulate);
            app.tensileButton.Text = 'tensile';
            app.tensileButton.Value = true;
            app.compressiveButton = uiradiobutton(app.ButtonGroupStrain_Simulate);
            app.compressiveButton.Text = 'compressive';
            app.CheckBox = uicheckbox(app.ButtonGroupStrain_Simulate);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ResetallButton = uibutton(app.SimulateTab, 'push');
            app.ResetallButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallButtonPushed, true);
            app.ResetallButton.Enable = 'off';
            app.ResetallButton.Text = 'Reset all';
            app.ImportsampleButton = uibutton(app.SimulateTab, 'push');
            app.ImportsampleButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsampleButtonPushed, true);
            app.ImportsampleButton.Enable = 'off';
            app.ImportsampleButton.Text = 'Import sample';
            app.ExportsampleButton = uibutton(app.SimulateTab, 'push');
            app.ExportsampleButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsampleButtonPushed, true);
            app.ExportsampleButton.Enable = 'off';
            app.ExportsampleButton.Text = 'Export sample';
            app.SampleNameTextAreaLabel_2 = uilabel(app.SimulateTab);
            app.SampleNameTextAreaLabel_2.Text = 'Thin film';
            app.RoughnessSwitch = uiswitch(app.SimulateTab, 'slider');
            app.RoughnessSwitch.Items = {'No', 'Yes'};
            app.RoughnessSwitch.ValueChangedFcn = createCallbackFcn(app, @RoughnessSwitchValueChanged, true);
            app.RoughnessSwitch.Enable = 'off';
            app.RoughnessSwitch.Tooltip = {'Do you want to include roughness of your layer?'; 'Please keep in mind that including roughness in the fit may increase the computation time to several minutes per iteration.'; 'Default value for the simulation if you are also fitting: sigma = 4 Å'; 'The value can be adjusted in the next tab.'};
            app.RoughnessSwitch.Value = 'No';
            app.StrainedSwitch = uiswitch(app.SimulateTab, 'slider');
            app.StrainedSwitch.Items = {'No', 'Yes'};
            app.StrainedSwitch.ValueChangedFcn = createCallbackFcn(app, @StrainedSwitchValueChanged, true);
            app.StrainedSwitch.Enable = 'off';
            app.StrainedSwitch.Tooltip = {'Do you want to include strain in your layer?'; 'Default values for the simulation if you are also fitting: alpha_1 = 0.029 and alpha_2 = 0.004'; 'The values can be adjusted in the next tab.'};
            app.StrainedSwitch.Value = 'No';
            app.SampleNameTextAreaLabel_5 = uilabel(app.SimulateTab);
            app.SampleNameTextAreaLabel_5.Text = 'Roughness';
            app.WavelengthEditField_2Label_2 = uilabel(app.SimulateTab);
            app.WavelengthEditField_2Label_2.Text = 'DW pre-factor';
            app.AdjustPanel = uipanel(app.SimulateTab);
            app.AdjustPanel.Visible = 'off';
            app.AdjustLabel = uilabel(app.AdjustPanel);
            app.AdjustLabel.Text = ' Adjust';
            app.sigmaLabel = uilabel(app.AdjustPanel);
            app.sigmaLabel.Text = 'sigma [Å]';
            app.sigmaEditField = uieditfield(app.AdjustPanel, 'numeric');
            app.sigmaEditField.Enable = 'off';
            app.sigmaEditField.Tooltip = {'adjust the roughness'};
            app.sigmaEditField.Value = 4;
            app.alpha_1EditFieldLabel = uilabel(app.AdjustPanel);
            app.alpha_1EditFieldLabel.Text = 'alpha_1';
            app.alpha_1EditField = uieditfield(app.AdjustPanel, 'numeric');
            app.alpha_1EditField.Enable = 'off';
            app.alpha_1EditField.Tooltip = {'adjust the strain parameter'};
            app.alpha_1EditField.Value = 0.029;
            app.alpha_2EditFieldLabel = uilabel(app.AdjustPanel);
            app.alpha_2EditFieldLabel.Text = 'alpha_2';
            app.alpha_2EditField = uieditfield(app.AdjustPanel, 'numeric');
            app.alpha_2EditField.Enable = 'off';
            app.alpha_2EditField.Tooltip = {'adjust the strain parameter'};
            app.alpha_2EditField.Value = 0.004;
            app.ExportgraphButton = uibutton(app.AdjustPanel, 'push');
            app.ExportgraphButton.ButtonPushedFcn = createCallbackFcn(app, @ExportgraphButtonPushed, true);
            app.ExportgraphButton.Text = 'Export graph';
            app.FitTab = uitab(app.TabGroup);
            app.FitTab.Title = 'Fit';
            app.Plotting_Fit = uiaxes(app.FitTab);
            xlabel(app.Plotting_Fit, '2 Theta [degrees]')
            ylabel(app.Plotting_Fit, 'Intensity [counts]')
            zlabel(app.Plotting_Fit, 'Z')
            app.Plotting_Fit.XLim = [60 70];
            app.ButtonGroup_4 = uibuttongroup(app.FitTab);
            app.ButtonGroup_4.Tooltip = {''};
            app.ButtonGroup_3 = uibuttongroup(app.FitTab);
            app.ExportparameterButton = uibutton(app.ButtonGroup_3, 'push');
            app.ExportparameterButton.ButtonPushedFcn = createCallbackFcn(app, @ExportparameterButtonPushed, true);
            app.ExportparameterButton.Enable = 'off';
            app.ExportparameterButton.Text = 'Export parameter';
            app.ExportfitButton = uibutton(app.ButtonGroup_3, 'push');
            app.ExportfitButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfitButtonPushed, true);
            app.ExportfitButton.Enable = 'off';
            app.ExportfitButton.Text = 'Export fit';
            app.XaxisLabel_2 = uilabel(app.ButtonGroup_3);
            app.XaxisLabel_2.Text = 'X-axis:';
            app.StartLabel_4 = uilabel(app.ButtonGroup_3);
            app.StartLabel_4.Text = 'Start';
            app.x_startEditField_Fit = uieditfield(app.ButtonGroup_3, 'numeric');
            app.x_startEditField_Fit.ValueChangedFcn = createCallbackFcn(app, @x_startEditField_FitValueChanged, true);
            app.x_startEditField_Fit.Tooltip = {'start value of x-axis'};
            app.x_startEditField_Fit.Value = 60;
            app.EndLabel_3 = uilabel(app.ButtonGroup_3);
            app.EndLabel_3.Text = 'End';
            app.x_endEditField_Fit = uieditfield(app.ButtonGroup_3, 'numeric');
            app.x_endEditField_Fit.ValueChangedFcn = createCallbackFcn(app, @x_startEditField_FitValueChanged, true);
            app.x_endEditField_Fit.Tooltip = {'end value of x-axis'};
            app.x_endEditField_Fit.Value = 70;
            app.PlotLabel_2 = uilabel(app.ButtonGroup_3);
            app.PlotLabel_2.Text = 'Plot';
            app.PlotsettingsLabel_3 = uilabel(app.ButtonGroup_3);
            app.PlotsettingsLabel_3.Text = ' Plot settings ';
            app.ClearallButton_Fit = uibutton(app.FitTab, 'push');
            app.ClearallButton_Fit.ButtonPushedFcn = createCallbackFcn(app, @ClearallButton_FitPushed, true);
            app.ClearallButton_Fit.Tooltip = {'clear all tabs and start from scratch'};
            app.ClearallButton_Fit.Text = 'Clear all';
            app.PreviousButton_Fit = uibutton(app.FitTab, 'push');
            app.PreviousButton_Fit.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_FitPushed, true);
            app.PreviousButton_Fit.Text = 'Previous';
            app.FittingLabel = uilabel(app.FitTab);
            app.FittingLabel.Text = 'Fitting';
            app.StartfittingButton = uibutton(app.FitTab, 'push');
            app.StartfittingButton.ButtonPushedFcn = createCallbackFcn(app, @StartfittingButtonPushed, true);
            app.StartfittingButton.Enable = 'off';
            app.StartfittingButton.Text = 'Start fitting';
            app.StopfittingButton = uibutton(app.FitTab, 'push');
            app.StopfittingButton.ButtonPushedFcn = createCallbackFcn(app, @StopfittingButtonPushed, true);
            app.StopfittingButton.Enable = 'off';
            app.StopfittingButton.Tooltip = {'stop the fitting, fitting will be stopped after the next completed iteration'};
            app.StopfittingButton.Text = 'Stop fitting';
            app.dCheckBox = uicheckbox(app.FitTab);
            app.dCheckBox.Enable = 'off';
            app.dCheckBox.Tooltip = {'interplanar spacing between diffraction planes'};
            app.dCheckBox.Text = 'd [Å]';
            app.ParameterLabel = uilabel(app.FitTab);
            app.ParameterLabel.Text = 'Parameter';
            app.StartLabel_Fit = uilabel(app.FitTab);
            app.StartLabel_Fit.Text = 'Start';
            app.LowerlimitLabel = uilabel(app.FitTab);
            app.LowerlimitLabel.Text = 'Lower limit';
            app.d_startField = uieditfield(app.FitTab, 'numeric');
            app.d_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.d_startField.Enable = 'off';
            app.d_startField.Tooltip = {'interplanar spacing between diffraction planes'};
            app.d_limit_low = uieditfield(app.FitTab, 'numeric');
            app.d_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.d_limit_low.Enable = 'off';
            app.d_limit_low.Tooltip = {'interplanar spacing between diffraction planes'};
            app.NCheckBox = uicheckbox(app.FitTab);
            app.NCheckBox.Enable = 'off';
            app.NCheckBox.Tooltip = {'number of diffraction planes contributing to coherent scattering'};
            app.NCheckBox.Text = 'N';
            app.N_startField = uieditfield(app.FitTab, 'numeric');
            app.N_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.N_startField.Enable = 'off';
            app.N_startField.Tooltip = {'number of diffraction planes contributing to coherent scattering'};
            app.N_limit_low = uieditfield(app.FitTab, 'numeric');
            app.N_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.N_limit_low.Enable = 'off';
            app.N_limit_low.Tooltip = {'number of diffraction planes contributing to coherent scattering'};
            app.alpha_1CheckBox = uicheckbox(app.FitTab);
            app.alpha_1CheckBox.Enable = 'off';
            app.alpha_1CheckBox.Tooltip = {'strain parameter'};
            app.alpha_1CheckBox.Text = 'alpha_1';
            app.alpha1_startField = uieditfield(app.FitTab, 'numeric');
            app.alpha1_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.alpha1_startField.Enable = 'off';
            app.alpha1_startField.Tooltip = {'strain parameter'};
            app.alpha1_limit_low = uieditfield(app.FitTab, 'numeric');
            app.alpha1_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.alpha1_limit_low.Enable = 'off';
            app.alpha1_limit_low.Tooltip = {'strain parameter'};
            app.alpha_2CheckBox = uicheckbox(app.FitTab);
            app.alpha_2CheckBox.Enable = 'off';
            app.alpha_2CheckBox.Tooltip = {'strain parameter'};
            app.alpha_2CheckBox.Text = 'alpha_2';
            app.alpha2_startField = uieditfield(app.FitTab, 'numeric');
            app.alpha2_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.alpha2_startField.Enable = 'off';
            app.alpha2_startField.Tooltip = {'strain parameter'};
            app.alpha2_limit_low = uieditfield(app.FitTab, 'numeric');
            app.alpha2_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.alpha2_limit_low.Enable = 'off';
            app.alpha2_limit_low.Tooltip = {'strain parameter'};
            app.aCheckBox = uicheckbox(app.FitTab);
            app.aCheckBox.Enable = 'off';
            app.aCheckBox.Tooltip = {'background parameter'};
            app.aCheckBox.Text = 'a';
            app.bkga_startField = uieditfield(app.FitTab, 'numeric');
            app.bkga_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkga_startField.Enable = 'off';
            app.bkga_startField.Tooltip = {'background parameter'};
            app.bkga_limit_low = uieditfield(app.FitTab, 'numeric');
            app.bkga_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkga_limit_low.Enable = 'off';
            app.bkga_limit_low.Tooltip = {'background parameter'};
            app.bCheckBox = uicheckbox(app.FitTab);
            app.bCheckBox.Enable = 'off';
            app.bCheckBox.Tooltip = {'background parameter'};
            app.bCheckBox.Text = 'b';
            app.bkgb_startField = uieditfield(app.FitTab, 'numeric');
            app.bkgb_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkgb_startField.Enable = 'off';
            app.bkgb_startField.Tooltip = {'background parameter'};
            app.bkgb_limit_low = uieditfield(app.FitTab, 'numeric');
            app.bkgb_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkgb_limit_low.Enable = 'off';
            app.bkgb_limit_low.Tooltip = {'background parameter'};
            app.scalefactCheckBox = uicheckbox(app.FitTab);
            app.scalefactCheckBox.Enable = 'off';
            app.scalefactCheckBox.Text = 'scale fact.';
            app.amplitude_startField = uieditfield(app.FitTab, 'numeric');
            app.amplitude_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.amplitude_startField.Enable = 'off';
            app.amplitude_limit_low = uieditfield(app.FitTab, 'numeric');
            app.amplitude_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.amplitude_limit_low.Enable = 'off';
            app.resolutionCheckBox = uicheckbox(app.FitTab);
            app.resolutionCheckBox.Enable = 'off';
            app.resolutionCheckBox.Text = 'resolution';
            app.resolution_startField = uieditfield(app.FitTab, 'numeric');
            app.resolution_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.resolution_startField.Enable = 'off';
            app.resolution_limit_low = uieditfield(app.FitTab, 'numeric');
            app.resolution_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.resolution_limit_low.Enable = 'off';
            app.MaxIterationsEditFieldLabel = uilabel(app.FitTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.FitTab, 'numeric');
            app.MaxIterationsEditField.Tooltip = {'number of fitting iterations'};
            app.MaxIterationsEditField.Value = 10;
            app.DisplayDropDownLabel = uilabel(app.FitTab);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.FitTab);
            app.DisplayDropDown.Items = {'Fitting', 'Parameters', 'Figure of merit', 'Strain profile'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Enable = 'off';
            app.DisplayDropDown.Value = 'Fitting';
            app.d_limit_high = uieditfield(app.FitTab, 'numeric');
            app.d_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.d_limit_high.Enable = 'off';
            app.N_limit_high = uieditfield(app.FitTab, 'numeric');
            app.N_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.N_limit_high.Enable = 'off';
            app.alpha1_limit_high = uieditfield(app.FitTab, 'numeric');
            app.alpha1_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.alpha1_limit_high.Enable = 'off';
            app.alpha2_limit_high = uieditfield(app.FitTab, 'numeric');
            app.alpha2_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.alpha2_limit_high.Enable = 'off';
            app.bkga_limit_high = uieditfield(app.FitTab, 'numeric');
            app.bkga_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkga_limit_high.Enable = 'off';
            app.bkgb_limit_high = uieditfield(app.FitTab, 'numeric');
            app.bkgb_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkgb_limit_high.Enable = 'off';
            app.amplitude_limit_high = uieditfield(app.FitTab, 'numeric');
            app.amplitude_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.amplitude_limit_high.Enable = 'off';
            app.resolution_limit_high = uieditfield(app.FitTab, 'numeric');
            app.resolution_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.resolution_limit_high.Enable = 'off';
            app.UpperlimitLabel = uilabel(app.FitTab);
            app.UpperlimitLabel.Text = 'Upper limit';
            app.ButtonGroupSpacePlot_Fit = uibuttongroup(app.FitTab);
            app.ButtonGroupSpacePlot_Fit.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSpacePlot_FitSelectionChanged, true);
            app.ThetaButton_Plot_Fit = uiradiobutton(app.ButtonGroupSpacePlot_Fit);
            app.ThetaButton_Plot_Fit.Text = '2 Theta';
            app.ThetaButton_Plot_Fit.Value = true;
            app.QButton_Plot_Fit = uiradiobutton(app.ButtonGroupSpacePlot_Fit);
            app.QButton_Plot_Fit.Text = 'Q';
            app.ButtonGroupUnitPlot_Fit = uibuttongroup(app.FitTab);
            app.ButtonGroupUnitPlot_Fit.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupUnitPlot_FitSelectionChanged, true);
            app.countsButton_Plot_Fit = uiradiobutton(app.ButtonGroupUnitPlot_Fit);
            app.countsButton_Plot_Fit.Text = 'counts';
            app.countsButton_Plot_Fit.Value = true;
            app.cpsButton_Plot_Fit = uiradiobutton(app.ButtonGroupUnitPlot_Fit);
            app.cpsButton_Plot_Fit.Text = 'cps';
            app.sigma_limit_high = uieditfield(app.FitTab, 'numeric');
            app.sigma_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.sigma_limit_high.Enable = 'off';
            app.sigmaCheckBox = uicheckbox(app.FitTab);
            app.sigmaCheckBox.Enable = 'off';
            app.sigmaCheckBox.Tooltip = {'layer roughness, put a value larger than 0 Å if you want to include it'};
            app.sigmaCheckBox.Text = 'sigma [Å]';
            app.sigma_startField = uieditfield(app.FitTab, 'numeric');
            app.sigma_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.sigma_startField.Enable = 'off';
            app.sigma_startField.Tooltip = {'layer roughness'};
            app.sigma_limit_low = uieditfield(app.FitTab, 'numeric');
            app.sigma_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.sigma_limit_low.Enable = 'off';
            app.sigma_limit_low.Tooltip = {'layer roughness'};
            app.bkgc_limit_high = uieditfield(app.FitTab, 'numeric');
            app.bkgc_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkgc_limit_high.Enable = 'off';
            app.cCheckBox = uicheckbox(app.FitTab);
            app.cCheckBox.Enable = 'off';
            app.cCheckBox.Tooltip = {'background parameter'};
            app.cCheckBox.Text = 'c';
            app.bkgc_startField = uieditfield(app.FitTab, 'numeric');
            app.bkgc_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkgc_startField.Enable = 'off';
            app.bkgc_startField.Tooltip = {'background parameter'};
            app.bkgc_limit_low = uieditfield(app.FitTab, 'numeric');
            app.bkgc_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.bkgc_limit_low.Enable = 'off';
            app.bkgc_limit_low.Tooltip = {'background parameter'};
            app.I_limit_high = uieditfield(app.FitTab, 'numeric');
            app.I_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.I_limit_high.Enable = 'off';
            app.w_limit_high = uieditfield(app.FitTab, 'numeric');
            app.w_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.w_limit_high.Enable = 'off';
            app.dsub_limit_high = uieditfield(app.FitTab, 'numeric');
            app.dsub_limit_high.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.dsub_limit_high.Enable = 'off';
            app.I_subCheckBox = uicheckbox(app.FitTab);
            app.I_subCheckBox.Enable = 'off';
            app.I_subCheckBox.Tooltip = {'intensity of the substrate peak'};
            app.I_subCheckBox.Text = 'I_sub';
            app.I_startField = uieditfield(app.FitTab, 'numeric');
            app.I_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.I_startField.Enable = 'off';
            app.I_startField.Tooltip = {'intensity of the substrate peak'};
            app.I_limit_low = uieditfield(app.FitTab, 'numeric');
            app.I_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.I_limit_low.Enable = 'off';
            app.I_limit_low.Tooltip = {'intensity of the substrate peak'};
            app.w_subCheckBox = uicheckbox(app.FitTab);
            app.w_subCheckBox.Enable = 'off';
            app.w_subCheckBox.Tooltip = {'width of the substrate peak (HWHM)'};
            app.w_subCheckBox.Text = 'w_sub';
            app.w_startField = uieditfield(app.FitTab, 'numeric');
            app.w_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.w_startField.Enable = 'off';
            app.w_startField.Tooltip = {'width of the substrate peak'};
            app.w_limit_low = uieditfield(app.FitTab, 'numeric');
            app.w_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.w_limit_low.Enable = 'off';
            app.w_limit_low.Tooltip = {'width of the substrate peak'};
            app.dsubCheckBox = uicheckbox(app.FitTab);
            app.dsubCheckBox.Enable = 'off';
            app.dsubCheckBox.Tooltip = {'interplanar spacing of substrate diffraction planes'};
            app.dsubCheckBox.Text = 'd(sub) [Å]';
            app.dsub_startField = uieditfield(app.FitTab, 'numeric');
            app.dsub_startField.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.dsub_startField.Enable = 'off';
            app.dsub_startField.Tooltip = {'interplanar spacing of substrate diffraction planes'};
            app.dsub_limit_low = uieditfield(app.FitTab, 'numeric');
            app.dsub_limit_low.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.dsub_limit_low.Enable = 'off';
            app.dsub_limit_low.Tooltip = {'interplanar spacing of substrate diffraction planes'};
            app.BackgroundSwitchLabel = uilabel(app.FitTab);
            app.BackgroundSwitchLabel.Text = 'Background';
            app.BackgroundSwitch = uiswitch(app.FitTab, 'slider');
            app.BackgroundSwitch.Items = {'linear', 'polynomial'};
            app.BackgroundSwitch.ValueChangedFcn = createCallbackFcn(app, @dsub_limit_highValueChanged, true);
            app.BackgroundSwitch.Enable = 'off';
            app.BackgroundSwitch.Value = 'linear';
            app.SimulateButton = uibutton(app.FitTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Tooltip = {'Simulate a diffraction patterns based on the fitting parameters on the left.'};
            app.SimulateButton.Text = 'Simulate';
            app.Gauge = uigauge(app.FitTab, 'linear');
            app.Gauge.MajorTicks = [0 20 40 60 80 100];
            app.Gauge.Enable = 'off';
            app.ProgressLabel = uilabel(app.FitTab);
            app.ProgressLabel.Text = 'Progress [%]';
            app.RefreshProgressButton = uibutton(app.FitTab, 'push');
            app.RefreshProgressButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshProgressButtonPushed, true);
            app.RefreshProgressButton.Enable = 'off';
            app.RefreshProgressButton.Tooltip = {'refresh the progress of the fitting, value will be updated after the next completed iteration'};
            app.RefreshProgressButton.Text = 'Refresh';
            app.FittingparametersLabel = uilabel(app.FitTab);
            app.FittingparametersLabel.Text = ' Fitting parameters ';
            % Show the figure after all components are created
            app.GenLversion10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fit_laue_oscillations_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenLversion10UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenLversion10UIFigure)
        end
    end
end